classdef GUISpaceDebrisSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DataHandlingMenu               matlab.ui.container.Menu
        SaveDataMenu                   matlab.ui.container.Menu
        SaveDataasMenu                 matlab.ui.container.Menu
        LoadDataMenu                   matlab.ui.container.Menu
        SaveSettingsMenu               matlab.ui.container.Menu
        LoadSettingsMenu               matlab.ui.container.Menu
        LoadTLEMenu                    matlab.ui.container.Menu
        PlotsMenu                      matlab.ui.container.Menu
        AddPlotMenu                    matlab.ui.container.Menu
        DeletePlotMenu                 matlab.ui.container.Menu
        GridLayout7                    matlab.ui.container.GridLayout
        DataChangedLamp                matlab.ui.control.Lamp
        DataChangedLampLabel           matlab.ui.control.Label
        ClearPlotsFluxSimulationButtonGroup  matlab.ui.container.ButtonGroup
        yesButtonECP                   matlab.ui.control.RadioButton
        noButtonECP                    matlab.ui.control.RadioButton
        TabGroup3                      matlab.ui.container.TabGroup
        ObjMassvsFluxTab               matlab.ui.container.Tab
        UIAxes7                        matlab.ui.control.UIAxes
        ObjMassvstotalImpactsTab       matlab.ui.container.Tab
        UIAxes8                        matlab.ui.control.UIAxes
        AverageImpactsoverMissionTimeTab  matlab.ui.container.Tab
        UIAxes9                        matlab.ui.control.UIAxes
        RunFluxSimulationButton        matlab.ui.control.Button
        TimeandDatePanel               matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        InitialDateDatePicker          matlab.ui.control.DatePicker
        InitialDateDatePickerLabel     matlab.ui.control.Label
        UTCLabel                       matlab.ui.control.Label
        MinutesEditField               matlab.ui.control.NumericEditField
        TimeLabel                      matlab.ui.control.Label
        Ddot2Label                     matlab.ui.control.Label
        Ddot1Label                     matlab.ui.control.Label
        SecondsEditField               matlab.ui.control.NumericEditField
        HoursEditField                 matlab.ui.control.NumericEditField
        GravityModelPanel              matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        GravityModelDropDown           matlab.ui.control.DropDown
        GravityModelDropDownLabel      matlab.ui.control.Label
        ClearPlotsOrbitSimulationButtonGroup  matlab.ui.container.ButtonGroup
        noButton                       matlab.ui.control.RadioButton
        yesButton                      matlab.ui.control.RadioButton
        OrbitSimulationSpecificationsPanel  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ODEDropDown                    matlab.ui.control.DropDown
        ODEDropDownLabel               matlab.ui.control.Label
        AbsErrorTolEditField           matlab.ui.control.NumericEditField
        AbsErrorTolEditFieldLabel      matlab.ui.control.Label
        RelErrorTolEditField           matlab.ui.control.NumericEditField
        RelErrorTolEditFieldLabel      matlab.ui.control.Label
        StepSizeEditField              matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel         matlab.ui.control.Label
        FinalHeightEditField           matlab.ui.control.NumericEditField
        FinalHeightEditFieldLabel      matlab.ui.control.Label
        SimulationTimeEditField        matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel   matlab.ui.control.Label
        AbsErrUnitLabel                matlab.ui.control.Label
        RelErrUnitLabel                matlab.ui.control.Label
        StepSizeUnitLabel              matlab.ui.control.Label
        FinalHeightUnitLabel           matlab.ui.control.Label
        SimulationTimeUnitLabel        matlab.ui.control.Label
        ObjectParametersPanel          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        EvaluationModeButtonGroup      matlab.ui.container.ButtonGroup
        ReducedButton                  matlab.ui.control.RadioButton
        ComplexButton                  matlab.ui.control.RadioButton
        MinProjDragsailAreaEditFieldUnitLabel  matlab.ui.control.Label
        MinProjDragsailAreaEditField   matlab.ui.control.NumericEditField
        MinProjDragsailAreaEditFieldLabel  matlab.ui.control.Label
        DragsailSizeLabel              matlab.ui.control.Label
        CubesatSizeLabel               matlab.ui.control.Label
        StepnumberUnitLabel            matlab.ui.control.Label
        MaxProjDragsailAreaEditFieldUnitLabel  matlab.ui.control.Label
        ObjDepthUnitLabel              matlab.ui.control.Label
        ObjHeightUnitLabel             matlab.ui.control.Label
        ObjWidthUnitLabel              matlab.ui.control.Label
        StepnumberEditField            matlab.ui.control.NumericEditField
        StepnumberEditFieldLabel       matlab.ui.control.Label
        MaxProjDragsailAreaEditField   matlab.ui.control.NumericEditField
        MaxProjDragsailAreaEditFieldLabel  matlab.ui.control.Label
        ObjDepthEditField              matlab.ui.control.NumericEditField
        ObjDepthEditFieldLabel         matlab.ui.control.Label
        ObjHeightEditField             matlab.ui.control.NumericEditField
        ObjHeightEditFieldLabel        matlab.ui.control.Label
        ObjWidthEditField              matlab.ui.control.NumericEditField
        ObjWidthEditFieldLabel         matlab.ui.control.Label
        CdUnitLabel                    matlab.ui.control.Label
        MassUnitLabel                  matlab.ui.control.Label
        SpecOptionDropDown             matlab.ui.control.DropDown
        SpecOptionDropDownLabel        matlab.ui.control.Label
        CdEditField                    matlab.ui.control.NumericEditField
        CdEditFieldLabel               matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassEditFieldLabel             matlab.ui.control.Label
        BallisticCoefficientEditField  matlab.ui.control.EditField
        BallisticCoefficientEditFieldLabel  matlab.ui.control.Label
        LifttoDragratioEditField       matlab.ui.control.NumericEditField
        LifttoDragratioEditFieldLabel  matlab.ui.control.Label
        LitftoDragratioUnitLabel       matlab.ui.control.Label
        BallisticCoefficientUnitLabel  matlab.ui.control.Label
        AtmosphereModelPanel           matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        aphDropDown                    matlab.ui.control.DropDown
        aphDropDownLabel               matlab.ui.control.Label
        SolarFluxDropDown              matlab.ui.control.DropDown
        SolarFluxDropDownLabel         matlab.ui.control.Label
        AtmosphereModelsDropDown       matlab.ui.control.DropDown
        AtmosphereModelsDropDownLabel  matlab.ui.control.Label
        OrbitParametersPanel           matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        LPEditField                    matlab.ui.control.NumericEditField
        LPEditFieldLabel               matlab.ui.control.Label
        ALEditField                    matlab.ui.control.NumericEditField
        ALLabel                        matlab.ui.control.Label
        TLEditField                    matlab.ui.control.NumericEditField
        TLEditFieldLabel               matlab.ui.control.Label
        PhiDotEditField                matlab.ui.control.NumericEditField
        PhiDotEditFieldLabel           matlab.ui.control.Label
        TrueAnomalyEditField           matlab.ui.control.NumericEditField
        TrueAnomalyEditFieldLabel      matlab.ui.control.Label
        APEditField                    matlab.ui.control.NumericEditField
        APLabel                        matlab.ui.control.Label
        ThetaDotEditField              matlab.ui.control.NumericEditField
        ThetaDotEditFieldLabel         matlab.ui.control.Label
        PhiEditField                   matlab.ui.control.NumericEditField
        PhiEditFieldLabel              matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        ReferenceSystemDropDown        matlab.ui.control.DropDown
        ReferenceSystemDropDownLabel   matlab.ui.control.Label
        LANEditField                   matlab.ui.control.NumericEditField
        LANLabel                       matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        SemimajorAxisEditField         matlab.ui.control.NumericEditField
        SemimajorAxisEditFieldLabel    matlab.ui.control.Label
        InclinationEditField           matlab.ui.control.NumericEditField
        InclinationEditFieldLabel      matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        LPUnitLabel                    matlab.ui.control.Label
        ALUnitLabel                    matlab.ui.control.Label
        TLUnitLabel                    matlab.ui.control.Label
        rDotEditFieldLabel             matlab.ui.control.Label
        ThetaDotUnitLabel              matlab.ui.control.Label
        PhiDotUnitLabel                matlab.ui.control.Label
        rDotUnitLabel                  matlab.ui.control.Label
        rDotEditField                  matlab.ui.control.NumericEditField
        ThetaUnitLabel                 matlab.ui.control.Label
        TrueAnomalyUnitLabel           matlab.ui.control.Label
        APUnitLabel                    matlab.ui.control.Label
        LANUnitLabel                   matlab.ui.control.Label
        EccentricityUnitLabel          matlab.ui.control.Label
        SemimajoraxisUnitLabel         matlab.ui.control.Label
        InclinationUnitLabel           matlab.ui.control.Label
        PhiUnitLabel                   matlab.ui.control.Label
        HeightUnitLabel                matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        RunOrbitSimulationButton       matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        FinalValuesTab                 matlab.ui.container.Tab
        FinalValsTable                 matlab.ui.control.Table
        DataTable                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SimTableTab1                   matlab.ui.container.Tab
        SimTable1                      matlab.ui.control.Table
        Orbit2DTab                     matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        Orbit3DTab                     matlab.ui.container.Tab
        ShowEarthCheckBox              matlab.ui.control.CheckBox
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ColoredButton                  matlab.ui.control.RadioButton
        BlackWhiteButton               matlab.ui.control.RadioButton
        UIAxes5                        matlab.ui.control.UIAxes
        rhohTab                        matlab.ui.container.Tab
        notdefinedrhoLabel             matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        trTab                          matlab.ui.container.Tab
        AverageValuesCheckBox          matlab.ui.control.CheckBox
        SimulatedValuesCheckBox        matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        % Simulation arguments        
        ODE
        stepsize
        td
        hfinal
        RelTol
        AbsTol
        
        % Date            
        SimStartDates = datetime(2023,01,01,0,0,0)
        DateTime
        InitialDate
        Hours
        Minutes
        Seconds
        
        % Atmospheric model and conditions        
        aph
        atmosphere
        solarflux        
        
        % Gravity Model       
        GravityModel
        
        % Object specifications
        ModeECP
        ObjSpec % Structure for all specifications
        SpecOption
        ObjWidth
        ObjHeight
        ObjDepth
        MinProjDragsailArea
        MaxProjDragsailArea
        Stepnumber
        Mass
        Cd
        OS % Daten Spacecraft
        LtD
        b
        bInput
        
        % Input Orbital elements        
        a % semi-major axis
        e % eccentricity
        i % inclination
        LAN % longitude of the ascending node
        AP % argument of pericenter 
        f % true anomaly
        TL % true longitude
        AL % argument of latitude
        LP % longitude of periapsis
        
        % Output Orbital elements        
        SimOut % Simulationsergebnisse
        as % semi-major axis
        es % eccentricity
        is % inclination
        LANs % longitude of the ascending node
        APs % argument of pericenter 
        fs % true anomaly
        TLs % true longitude
        ALs % argument of latitude
        LPs % longitude of pericenter
        
        % Spherical Coordinates      
        r0
        h0
        Phi0
        Theta0
        rDot
        PhiDot
        ThetaDot
        
        % Additional Values
        OrbitSimSpec
        RefSys 
        FinalVals = double.empty
        SolutionAll = {}
        ColumnNamef = {}        
        Ra
        Rb
        SimN = int16(0)
        Tab
        Table
        filenames
        
        % Plott Settings  
        temphold
        holdsECP
        holds
        LegendArray
        EarthVis 
        EarthVis2
        Orbit3DPlot
        Orbit3DPlotList
        Orbit3DLeg
        trPlot
        trGroundPlot
        trhfinalPlot
        rhohPlot
        tgdummy
        opt_list
        xax
        yax
        XScale
        YScale
        PlotName 
        UIAxesCreate
        AddPlotArray = string.empty
        PlotData
        notdefinedLabel
        US % Structure for units in graphs
        DelPlot
        PlotTab

        % ECP Plott
        ECPPlot
    end
    
%%--------------------------------------------------------------------
%%--------------------------------------------------------------------
    methods (Access = private)
        function ECPPlotting(app,AllOrbitTotalResult,Result,ObjectMissionFlux,SigmResult)
            %%-------------------Creating Plots-----------------------                      
            my_field = strcat('ObjMass',num2str(1));
                      
            if strcmp(app.holdsECP,'off')
                cla(app.UIAxes7);
                cla(app.UIAxes8);
                cla(app.UIAxes9);
            end

            % Plot erstellen
            % Plot1
            app.UIAxes7.XScale = 'log';
            app.UIAxes7.Title.String = 'Impactor Mass vs Impact Flux';

            hold (app.UIAxes7,'on')
            x = Result(:,1,1);
            for m = 1 : size(Result,3)
                if strcmp(app.ObjSpec.SpecOption,'Cubesat with Dragsail')
                    if m == 1
                        Size = 'Cubesat';            
                        y.(Size) = Result(:,19,m); 
                        app.ECPPlot.(my_field) = stairs(app.UIAxes7, x, y.(Size),'DisplayName',Size);
                    else
                        Size = strcat('DragsailStep',num2str(m-1));

                        y.(Size) = Result(:,19,m);
                        app.ECPPlot.(my_field) = stairs(app.UIAxes7, x, y.(Size),'DisplayName',Size);
                    end
                elseif strcmp(app.ObjSpec.SpecOption,'Cubesat')
                    Size = 'Cubesat';            
                    y.(Size) = Result(:,19,m); 
                    app.ECPPlot.(my_field) = stairs(app.UIAxes7, x, y.(Size),'DisplayName',Size);
                else
                    Size = strcat('Sphere');            
                    y.(Size) = Result(:,19,m); 
                    app.ECPPlot.(my_field) = stairs(app.UIAxes7, x, y.(Size),'DisplayName',Size);
                    % Errorbars
                    if app.ModeECP == 1
                        ynom = SigmResult(:,2);
                        yneg = ynom-SigmResult(:,3);
                        ypos = SigmResult(:,4)-ynom;
                        x_error = [0.01 0.1 1 10 100 1000];
                        errorbar(app.UIAxes7, x_error, ynom, yneg, ypos, 'r', 'LineStyle','none','DisplayName','Uncertainty Bars');
                    end
                end
            end            

            % Achsenbeschriftungen
            xlabel(app.UIAxes7, 'Impactor Mass [kg]');
            ylabel(app.UIAxes7, 'Impact Flux [1/(m^2*yr)]');

            % Plot2
            app.UIAxes8.XScale = 'log';
            app.UIAxes8.Title.String = 'Impactor Mass vs Total Impacts';

            hold (app.UIAxes8,'on')
            x = AllOrbitTotalResult(:,1,1);
            for m = 1 : size(AllOrbitTotalResult,3)
                if strcmp(app.ObjSpec.SpecOption,'Cubesat with Dragsail')
                    if m == 1
                        Size = 'Cubesat';            
                        y.(Size) = AllOrbitTotalResult(:,19,m); 
                        app.ECPPlot.(my_field) = stairs(app.UIAxes8, x, y.(Size),'DisplayName',Size);
                    else
                        Size = strcat('DragsailStep',num2str(m-1));

                        y.(Size) = AllOrbitTotalResult(:,19,m);
                        app.ECPPlot.(my_field) = stairs(app.UIAxes8, x, y.(Size),'DisplayName',Size);
                    end
                elseif strcmp(app.ObjSpec.SpecOption,'Cubesat')
                    Size = 'Cubesat';            
                    y.(Size) = AllOrbitTotalResult(:,19,m); 
                    app.ECPPlot.(my_field) = stairs(app.UIAxes8, x, y.(Size),'DisplayName',Size);
                else
                    Size = strcat('Sphere',num2str(m));            
                    y.(Size) = AllOrbitTotalResult(:,19,m); 
                    app.ECPPlot.(my_field) = stairs(app.UIAxes8, x, y.(Size),'DisplayName',Size);
                end
            end            

            % Achsenbeschriftungen
            xlabel(app.UIAxes8, 'Impactor Mass [kg]');
            ylabel(app.UIAxes8, 'Total Impacts []');

            % Plot3
            app.UIAxes9.Title.String = 'Average Impacts over Mission Time';

            hold (app.UIAxes9,'on')

            for m = 1 : size(AllOrbitTotalResult,3)
                if strcmp(app.ObjSpec.SpecOption,'Cubesat with Dragsail')
                    if m == 1
                        x = ObjectMissionFlux.Cubesat.Total(1,:);

                        sumRows = sum(ObjectMissionFlux.Cubesat.Total(2:7, :), 1);
                        y1 = sumRows;

                        if size(ObjectMissionFlux.Cubesat.Total,2) == 1
                            app.ECPPlot.(my_field) = stairs(app.UIAxes9, [0 x], [y1 y1],'DisplayName','Cubesat Obj. Mass 1-6');
                        else
                            app.ECPPlot.(my_field) = stairs(app.UIAxes9, x, y1,'DisplayName','Cubesat Obj. Mass 1-6');
                        end
                    else
                        x = ObjectMissionFlux.Dragsail.Total(1+(8*(m-2)),:);
                        sumRows = sum(ObjectMissionFlux.Dragsail.Total(2+(8*(m-2)):7+(8*(m-2)), :), 1);
                        y1 = sumRows;

                        Size = strcat('DragsailStep',num2str(m-1));

                        if size(ObjectMissionFlux.Dragsail.Total,2) == 1
                            Size1 = strcat(Size,' Obj. Mass 1-6');
                            app.ECPPlot.(my_field) = stairs(app.UIAxes9, [0 x], [y1 y1],'DisplayName',Size1, 'LineStyle', '--');

                        else    
                            Size1 = strcat(Size,' Obj. Mass 1-6');
                            app.ECPPlot.(my_field) = stairs(app.UIAxes9, x, y1,'DisplayName',Size1, 'LineStyle', '--');
                        end
                    end
                elseif strcmp(app.ObjSpec.SpecOption,'Cubesat')
                    x = ObjectMissionFlux.Cubesat.Total(1,:);
                    sumRows = sum(ObjectMissionFlux.Cubesat.Total(2:7, :), 1);
                    y1 = sumRows;

                    if size(ObjectMissionFlux.Cubesat.Total,2) == 1
                        app.ECPPlot.(my_field) = stairs(app.UIAxes9, [0 x], [y1 y1],'DisplayName','Cubesat Obj. Mass 1-6');
                    else
                        app.ECPPlot.(my_field) = stairs(app.UIAxes9, x, y1,'DisplayName','Cubesat Obj. Mass 1-6');
                    end
                else    
                    x = ObjectMissionFlux.Sphere(1,:);
                    sumRows = sum(ObjectMissionFlux.Sphere(2:7, :), 1);
                    y1 = sumRows;

                    if size(ObjectMissionFlux.Sphere,2) == 1
                        app.ECPPlot.(my_field) = stairs(app.UIAxes9, [0 x], [y1 y1],'DisplayName','Sphere Obj. Mass 1-6');
                    else
                        app.ECPPlot.(my_field) = stairs(app.UIAxes9, x, y1,'DisplayName','Sphere Obj. Mass 1-6');
                    end
                end
            end            

            % Achsenbeschriftungen
            xlabel(app.UIAxes9, 'Mission Time [Days]');
            ylabel(app.UIAxes9, '2D Flux [1/yr]');

        end

        function plotting(app,SimOut,rhoV)                                          

            r = SimOut.r;                % r in [m]
            Phi = SimOut.Phi;            % Phi in [rad]
            Theta = SimOut.Theta;        % Theta in [rad]
            x = SimOut.x2D;              % x2D in [m]
            y = SimOut.y2D;              % y2D in [m]
            x3D = SimOut.x3D;            % x3D in [km]
            y3D = SimOut.y3D;            % y3D in [km]
            z3D = SimOut.z3D;            % z3D in [km]    
            vges = SimOut.Vges;          % v ges in [km/s]
            vgessp = SimOut.VgesSp;      %
    
            t = SimOut.t;               % t in [d]
            h = SimOut.altitude;        % altitude in [km]
            E = SimOut.epsilon;         % epsilon in [m^2/s^2]
            
            % Extracting final values from solution
            
            hf = h(end,1);              % converts hf in [km]
            tf = t(end,1);              % final time in [d]
            revs = Phi(end,1)/(360);    % number of revolution around the Earth
            rl = (app.h0-h(end,1));     % loss of height [km]

            %Orbit Elements

            app.es = SimOut.e;     % extract eccentricity
            ef = (app.es(end,1));  % final eccentricity
            app.as = SimOut.a;     % extract semi-major axis
            af = app.as(end);      % final Semi-major axis in [km]
            app.is = SimOut.i;     % extract inclination
            app.APs = SimOut.AP;   % extract argument of pericenter
            app.LANs = SimOut.LAN; % extract longitude of ascending node
            app.fs = SimOut.f;     % extract true anomaly
            app.TLs = SimOut.TL;   % extract
            app.ALs = SimOut.AL;   % extract 
            app.LPs = SimOut.LP;   % extract 
            
            %%---------------------Create tables----------------------
            
            my_field = strcat('Sim',num2str(app.SimN));
                        
            if app.SimN > 1  
                
                % Adding Simulation Date For ECP Function
                app.SimStartDates = cat(1, app.SimStartDates, app.DateTime);

                % Data table
                TitleStr = append('Sim ',string(app.SimN)); 
                app.Tab.(my_field) = uitab(app.TabGroup2,'Title',TitleStr);                  
                app.SolutionAll = [t(:), r(:), Phi(:), h(:),vges(:),app.as(:), app.es(:),app.fs(:),app.APs(:),app.LANs(:),app.is(:),app.TLs(:), app.ALs(:), app.LPs(:),Theta(:),x(:),y(:),x3D(:),y3D(:),z3D(:),vgessp(:),E(:)];      
                app.Table.(my_field) = uitable (app.Tab.(my_field), 'Position',[0,3,587,389],'Data', app.SolutionAll);
                app.Table.(my_field).ColumnName = {'t [d]','r [km]','phi [°]','h [km]','vges [km/s]','a [km]','e []','f [°]','AoP [°]','RAAN[°]','i [°]','TL[]','AL[]','LP[]','Theta[]','x2D[]','y2D[]','x3D[]','y3D[]','z3D[]','VgesSp[]','epsilon[]'};
                
                % Final values table
                app.ColumnNamef = [app.FinalValsTable.ColumnName;TitleStr];
                app.FinalVals = [app.FinalVals,num2cell([hf ; tf ; revs ; rl ; af ; ef])];
                set(app.FinalValsTable,'ColumnName',app.ColumnNamef, 'Data', app.FinalVals);
                
            elseif app.SimN == 1
                
                % Adding Simulation Date For ECP Function
                app.SimStartDates = app.DateTime;

                % Data table 
                set(app.SimTable1, 'Data', app.SolutionAll);                
                app.SolutionAll = [t(:), r(:), Phi(:), h(:),vges(:),app.as(:), app.es(:),app.fs(:),app.APs(:),app.LANs(:),app.is(:),app.TLs(:), app.ALs(:), app.LPs(:),Theta(:),x(:),y(:),x3D(:),y3D(:),z3D(:),vgessp(:),E(:)];    
                set(app.SimTable1, 'Data', app.SolutionAll);
                app.SimTable1.ColumnName = {'t [d]','r [km]','phi [°]','h [km]','vges [km/s]','a [km]','e []','f [°]','AoP [°]','RAAN[°]','i [°]','TL[]','AL[]','LP[]','Theta[]','x2D[]','y2D[]','x3D[]','y3D[]','z3D[]','VgesSp[]','epsilon[]'};
                
                % Final values table
                app.ColumnNamef = ['';'Sim 1'];
                app.FinalVals = {hf ; tf ; revs ; rl ; af ; ef};
                set(app.FinalValsTable, 'ColumnName',app.ColumnNamef, 'Data', app.FinalVals);
                
            end
                
            %%-------------------Creating Plots-----------------------                      
            
            LegendStr = append('Sim ',string(app.SimN));
            app.LegendArray = [app.LegendArray LegendStr];
            
            
            %----------------------Plot (t,r)-------------------------Final height für unterschiedliche Einstellungen !!!!!!!!!!!!!!!!!!!
            hold (app.UIAxes,'on') 
            app.trPlot.(my_field) = plot(app.UIAxes,t,r,'DisplayName',LegendStr);
            
            % Create additional lines
            Relips = sqrt((app.Ra*cos(Theta(end)))^2+(app.Rb*sin(Theta(end)))^2);    % WGS84 Ellipsoid radius [m]
            if (r(size(r,1))-Relips <= 200) && isempty(app.trGroundPlot)
                app.trGroundPlot = yline(app.UIAxes,Relips, 'r--', 'LineWidth', 1,'DisplayName','Ellipsoid height');
            end
            if (r(size(r,1))-(Relips+app.hfinal) <= 200) && isempty(app.trhfinalPlot)
                app.trhfinalPlot = yline(app.UIAxes,Relips+app.hfinal, 'g--', 'LineWidth', 1,'DisplayName','Final height');
            end
            %plot(app.UIAxes,aver(:,1),aver(:,3));
            ylabel(app.UIAxes,'r [km]'), xlabel(app.UIAxes,'t [d]'); % generates labels
            hold off
            
            
            %-------------------Plot (Orbit)--------------------------Show Correct for i > 0 and ellipsoid!!!!!!!!!!!!!!!!!
            hold (app.UIAxes2,'on')
            plot(app.UIAxes2,x,y,'DisplayName',LegendStr); % plot trajecory
            plotdim = 1.05*max(r); % plot dimension
            earthdim = 2*app.Ra; % Earth diameter in plot
            xlim(app.UIAxes2,[-plotdim plotdim]); % x-axis dimension
            ylim(app.UIAxes2,[-plotdim plotdim]); % y-axis dimension
            pbaspect(app.UIAxes2,[1 1 1]) % aspect ration (makes plot quadratic)
            xlabel(app.UIAxes2,'x [km]'), ylabel(app.UIAxes2,'y [km]'); % generates labels           
            rectangle(app.UIAxes2,'Position',[-earthdim/2 -earthdim/2 earthdim earthdim],'Curvature',[1,1],'FaceColor','b');
            hold off                
            
            
            %-----------------Plot 3D Orbit---------------------------
            hold (app.UIAxes5,'on')
            
            % add Orbit to Earth            
            app.Orbit3DPlot.(my_field) = plot3(app.UIAxes5,x3D,y3D,z3D,'DisplayName',LegendStr); % Plot trajectory
            app.Orbit3DPlotList = [app.Orbit3DPlotList app.Orbit3DPlot.(my_field)];            
            
            % Create the sphere with Earth topography and adjust colormap            
            if isnumeric (app.EarthVis)
                set(app.ButtonGroup, 'BackgroundColor','k'); 
                set(app.ColoredButton,'FontColor',[0.588, 0.588, 0.588]);
                set(app.BlackWhiteButton,'FontColor',[0.588, 0.588, 0.588]);
                set(app.ShowEarthCheckBox, 'FontColor',[0.588, 0.588, 0.588])
                space_color = 'k';
                axisColor = [0.588, 0.588, 0.588];
                npanels = 180;   % Number of globe panels around the equator deg/panel = 360/npanels
                alpha   = 1; % globe transparency level, 1 = opaque, through 0 = invisible            
                
                % Earth texture image
                % Anything imread() will handle, but needs to be a 2:1 unprojected globe
                % image.                
                image_file = 'Earth.jpg';
                image_file2 = 'BWEarth3.jpg';
                
                % Mean spherical earth
        	    erad    = 6371.0087714; % equatorial radius (km)
                prad    = 6371.0087714; % polar radius (km)
                
                % Create figure                    
                
                % Turn off the normal axes
                app.Orbit3DTab.BackgroundColor = 'k';
                set(app.UIAxes5,'View',[127.5 30],'GridColor',[1, 1, 1],'XColor',axisColor,'YColor',axisColor,'ZColor',axisColor,'Color', space_color,'DataAspectRatio',[1 1 1], 'NextPlot','add');              
                
                % Set initial view
                
                % Create wireframe globe
                % Create a 3D meshgrid of the sphere points using the ellipsoid function
                [xx, yy, zz] = ellipsoid(0, 0, 0, erad, erad, prad, npanels);               
                
                % Create Colored Earth 
                cdata = imread(image_file);
                app.EarthVis = surf(app.UIAxes5,-xx,yy,-zz,'FaceColor', 'none', 'EdgeColor', 0.5*[1 1 1],'SpecularStrength',0.5,'AmbientStrength',0.8,'DisplayName','Earth');                
                set(app.EarthVis, 'FaceColor', 'texturemap', 'CData', cdata, 'FaceAlpha', alpha, 'EdgeColor', 'none')
                
                % Create black and white Earth                
                cdata = imread(image_file2);
                app.EarthVis2 = surf(app.UIAxes5,-xx,yy,-zz,'FaceColor', 'none', 'EdgeColor','k','DiffuseStrength',0.5,'AmbientStrength',0.5,'SpecularStrength',0.2,'SpecularColorReflectance',0.2,'DisplayName','Earth2');                
                set(app.EarthVis2, 'FaceColor', 'texturemap', 'CData', cdata, 'FaceAlpha', alpha, 'EdgeColor', 'none','visible','off')
                camlight(app.UIAxes5,'left')
                
                app.ShowEarthCheckBox.Enable = 'on';
                app.BlackWhiteButton.Enable = 'on';
                app.ColoredButton.Enable = 'on';
                
                xlabel(app.UIAxes5,'X [km]')
                ylabel(app.UIAxes5,'Y [km]')
                zlabel(app.UIAxes5,'Z [km]')
            end
            app.Orbit3DLeg = legend(app.UIAxes5,[app.Orbit3DPlotList],app.LegendArray,'Color','k','TextColor',[0.588, 0.588, 0.588],'Box','off');
            hold off
            
            
            %----------------------------Plot (rho,h)-----------------
            if ~isempty(rhoV) 
                hold (app.UIAxes6,'on')
                app.rhohPlot.(my_field) = plot(app.UIAxes6,rhoV(:,1),rhoV(:,3),'DisplayName',LegendStr);
                xlabel(app.UIAxes6,'rho [kg/m^3]'), ylabel(app.UIAxes6,'h [km]'); % generates labels
                hold off
                app.notdefinedrhoLabel.Visible = 'off';
            end
            
            
            %------------------------Create additional plots----------
            for ii = 1 : (size(app.AddPlotArray,1))
                app.PlotName = app.AddPlotArray(ii,4);
                hold (app.UIAxesCreate.(app.PlotName),'on')
                app.PlotData.(app.PlotName) = plot(app.UIAxesCreate.(app.PlotName),SimOut.(app.AddPlotArray(ii,1)),SimOut.(app.AddPlotArray(ii,2)),'DisplayName',LegendStr);
                hold off
                
                % Control of "not defined" label
                if any(~isnan(SimOut.(app.AddPlotArray(ii,1))(:))) && any(~isnan(SimOut.(app.AddPlotArray(ii,2))(:)))   
                    app.notdefinedLabel.(app.PlotName).Visible = 'off';
                end
            end  
            
        end

%%--------------------------------------------------------------------
        function SaveData(app,sender)
            if app.SimN == 0
                uialert(app.UIFigure,'No Data found!', 'Orbitsimulation','Icon','warning'); 
            else
                if strcmp(sender,'save')
                    now = datetime('now','Format','dd-MMM-yyyy-HH-mm-ss');
                    filename = fullfile('.','Output',sprintf('Solution-%s.xlsx',now));
                    indx = 2;
                elseif strcmp(sender,'saveas')                
                    title = 'Simulation Data';
                    filter = {'*.csv';'*.xlsx'};
                    [file, path,indx] = uiputfile(filter,title) ;
                    filename = fullfile(path,file);
                end
                selection = '';
                if indx ~= 0                
                    D1 = {'t [d]','r [km]','phi [°]','h [km]','vges [km/s]','a [km]','e []','f [°]','AoP [°]','RAAN[°]','i [°]','TL[]','AL[]','LP[]','Theta[]','x2D[]','y2D[]','x3D[]','y3D[]','z3D[]','VgesSp[]','epsilon[]'};          
                    for ii = 1 : app.SimN 
                        if ii == 1
                            my_field = strcat('Sim',num2str(ii));
                            D2 = num2cell(app.SimTable1.Data);
                            SaveDat = [D1;D2];
                        else 
                            my_field = strcat('Sim',num2str(ii));
                            D2 = num2cell(app.Table.(my_field).Data);
                            SaveDat = [D1;D2];
                        end
                        
                        if (size(SaveDat,1) > 1048576) || (indx == 1)                            
                            if indx == 2
                                selection = uiconfirm(app.UIFigure,'Number of rows exceeds the maximum of rows in Excel. Do you want to write one or more .csv files?', 'Orbitsimulation','Icon','warning'); 
                            end
                            if (strcmp(selection,'OK')) || (indx == 1)
                                [folder, baseFileName] = fileparts(filename);
                                % Ignore extension and replace it with .csv
                                if ii == 1
                                    newBaseFileName = append(baseFileName,'.csv');
                                else
                                    newBaseFileName = append(baseFileName,'_',string(ii),'.csv');
                                end
                                % Make sure the folder is prepended (if it has one).
                                app.filenames = fullfile(folder, newBaseFileName);
                                writecell(SaveDat,app.filenames) 
                            end
                        elseif (size(SaveDat,1) < 1048576) && (indx == 2)
                            writecell(SaveDat,filename,'Sheet',my_field,'Range','A1') 
                        end       
                    end
                    [folder, baseFileName] = fileparts(filename);
                    newBaseFileName = append(baseFileName,'.ini');
                    app.filenames = fullfile(folder, newBaseFileName);
                    SaveSettingsMenuSelected(app)
                    if ~strcmp(selection,'Cancel')
                        Message = append('File(s) succesfully saved!');
                        uialert(app.UIFigure,Message, 'Orbitsimulation','Icon','success');
                    end
                end
            end
        end

%%--------------------------------------------------------------------        
        function eccincchange(app)
             if app.e == 0 && (app.i == 0||app.i==180) 
                %app.LPEditField.Enable = 'off';
                %app.TLEditField.Enable = 'on';
                %app.ALEditField.Enable = 'off';
                app.LANEditField.Enable = 'off';
                %app.TrueAnomalyEditField.Enable = 'off';
                %app.APEditField.Enable = 'off';
            elseif app.e == 0 && app.i ~= 0
                %app.LPEditField.Enable = 'off';
                %app.TLEditField.Enable = 'off';
                %app.ALEditField.Enable = 'on';
                app.LANEditField.Enable = 'on';
                %app.TrueAnomalyEditField.Enable = 'off';
                %app.APEditField.Enable = 'off';
            elseif app.i == 0 && app.e ~= 0
                %app.LPEditField.Enable = 'on';
                %app.TLEditField.Enable = 'off';
                %app.ALEditField.Enable = 'off';
                app.LANEditField.Enable = 'off';
                %app.TrueAnomalyEditField.Enable = 'on';
                %app.APEditField.Enable = 'off';
            elseif app.i ~= 0 && app.e ~= 0
                %app.LPEditField.Enable = 'off';
                %app.TLEditField.Enable = 'off';
                %app.ALEditField.Enable = 'off';
                app.LANEditField.Enable = 'on';
                %app.TrueAnomalyEditField.Enable = 'on';
                %app.APEditField.Enable = 'on';
            end            
        end
        
%%--------------------------------------------------------------------        
        function CreatePlot(app)

            app.PlotName = append(app.xax,app.yax); 
            app.PlotTab.(app.PlotName) = uitab(app.TabGroup); 
            app.PlotTab.(app.PlotName).Title = append(app.xax,",",app.yax);
            app.UIAxesCreate.(app.PlotName) = uiaxes(app.PlotTab.(app.PlotName));
            legend(app.UIAxesCreate.(app.PlotName))
            app.UIAxesCreate.(app.PlotName).Position = [0 0 584,414];
            app.UIAxesCreate.(app.PlotName).XGrid = 'on';
            app.UIAxesCreate.(app.PlotName).YGrid = 'on';
            app.UIAxesCreate.(app.PlotName).Title.String = append(app.xax,",",app.yax);
            app.UIAxesCreate.(app.PlotName).XLabel.String = app.US.(app.xax);
            app.UIAxesCreate.(app.PlotName).YLabel.String = app.US.(app.yax);
            app.UIAxesCreate.(app.PlotName).XScale = app.XScale;
            app.UIAxesCreate.(app.PlotName).YScale = app.YScale;
            app.UIAxesCreate.(app.PlotName).Legend.Location = 'northeast'; 
            app.UIAxesCreate.(app.PlotName).Visible = 'on';
            
            
            app.notdefinedLabel.(app.PlotName) = uilabel(app.PlotTab.(app.PlotName));
            app.notdefinedLabel.(app.PlotName).Text = 'not defined';
            app.notdefinedLabel.(app.PlotName).FontSize = 80;
            app.notdefinedLabel.(app.PlotName).FontColor = [0.99 0.44 0.44];
            app.notdefinedLabel.(app.PlotName).Position = [134,192,408,98];
            
        end
          
        function ClearPlots(app)
            cla(app.UIAxes) 
            cla(app.UIAxes2) 
            cla(app.UIAxes5) 
            cla(app.UIAxes6) 
            app.notdefinedrhoLabel.Visible = 'on';
            app.EarthVis = [];
            for ii = 1 : (size(app.AddPlotArray,1))
                app.xax = app.AddPlotArray(ii,1);
                app.yax = app.AddPlotArray(ii,2);
                app.PlotName = append(app.xax,app.yax); 
                cla(app.UIAxesCreate.(app.PlotName));
                app.notdefinedLabel.(app.PlotName).Visible = 'on';
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%              try
                 oldpointer = get(app.UIFigure, 'pointer');
                 set(app.UIFigure, 'pointer', 'watch');
                 drawnow;
                 
        %         app.rDotEditFieldLabel.Text = [char([double('r'),775]) ''];
        %         app.PhiEditFieldLabel.Text = char(966);
        %         app.PhiDotEditFieldLabel.Text = [char([double(char(966)),775]) ''];
         
        %         app.ThetaEditFieldLabel.Text = char(952);
        %         app.ThetaDotEditFieldLabel.Text = [char([double(char(952)),775]) ''];
                
                app.Ra = 6378.1370;     % Earth Semi-major axis in [km]
                app.Rb = 6356.7523142;  % Earth Semi-minor axis in [km]
        
                app.ThetaDotUnitLabel.Text= ['[' char(956) 'rad/s]'];
                app.PhiDotUnitLabel.Text= ['[' char(956) 'rad/s]'];
                
                app.filenames = '';

                app.RefSys = app.ReferenceSystemDropDown.Value; 
                
                % Spherical Coordinates
                app.h0 = app.HeightEditField.Value;
                app.Phi0 = app.PhiEditField.Value;
                app.Theta0 = app.ThetaEditField.Value;
                app.rDot = app.rDotEditField.Value;
                app.PhiDot = app.PhiDotEditField.Value;
                app.ThetaDot = app.ThetaDotEditField.Value;
                
                % Orbital Elements
                app.i = app.InclinationEditField.Value;
                app.a = app.SemimajorAxisEditField.Value;
                app.LAN = app.LANEditField.Value;
                app.e = app.EccentricityEditField.Value;
                app.AP = app.APEditField.Value;
                app.f = app.TrueAnomalyEditField.Value;        
                app.TL = app.TLEditField.Value;
                app.LP = app.LPEditField.Value;
                app.AL = app.ALEditField.Value;
                
                % Simulation Parameters
                app.ODE = '45';
                app.hfinal = app.FinalHeightEditField.Value;
                app.td = app.SimulationTimeEditField.Value;
                app.stepsize = app.StepSizeEditField.Value;
                app.RelTol = app.RelErrorTolEditField.Value;
                app.AbsTol = app.AbsErrorTolEditField.Value;
                
                % Object Parameters
                app.bInput = app.BallisticCoefficientEditField.Value;
                app.LtD = app.LifttoDragratioEditField.Value;
                app.SpecOption = app.SpecOptionDropDown.Value;
                app.ObjWidth = app.ObjWidthEditField.Value;
                app.ObjHeight = app.ObjHeightEditField.Value;
                app.ObjDepth = app.ObjDepthEditField.Value;
                app.MinProjDragsailArea = app.MinProjDragsailAreaEditField.Value;
                app.MaxProjDragsailArea = app.MaxProjDragsailAreaEditField.Value;
                app.Stepnumber = app.StepnumberEditField.Value;
                app.Mass = app.MassEditField.Value;
                app.Cd = app.CdEditField.Value;
                
                % Atmosphere
                app.Seconds = app.SecondsEditField.Value;
                app.Minutes = app.MinutesEditField.Value;
                app.Hours = app.HoursEditField.Value;
                app.InitialDate = app.InitialDateDatePicker.Value;        
                app.atmosphere = app.AtmosphereModelsDropDown.Value;
                app.solarflux = app.SolarFluxDropDown.Value;
                app.aph = app.aphDropDown.Value;
                
                % Gravity
                app.GravityModel = app.GravityModelDropDown.Value;

                % ECP Settings
                app.ModeECP = 2;

                % Plots
                app.holds = 'off';
                legend(app.UIAxes,'Location','northeast')
                app.UIAxes.Visible = 'on';
                legend(app.UIAxes2,'Location','northeast')
                app.UIAxes2.Visible = 'on';
                legend(app.UIAxes5,'Location','northeast')
                app.UIAxes5.Visible = 'on';
                legend(app.UIAxes6,'Location','northeast')
                app.UIAxes6.Visible = 'on';
                app.notdefinedrhoLabel.Position = [134,192,408,98];
                app.ShowEarthCheckBox.Position =[87,14,84,22];
                app.ButtonGroup.Position =[241,8,237,33];

                % ECP Plots
                app.holdsECP = 'off';
                legend(app.UIAxes7,'Location','northeast')
                app.UIAxes7.Visible = 'on';
                legend(app.UIAxes8,'Location','northeast')
                app.UIAxes8.Visible = 'on';
                legend(app.UIAxes9,'Location','northeast')
                app.UIAxes9.Visible = 'on';

                % Optionlist for creation of graphs
                app.opt_list = {'a','e','i','LAN','AP','f','TL','AL','LP','r','Phi','Theta','x3D','y3D','z3D','Vges','t','altitude','epsilon'};
                
                % Create unit structure
                app.US.a = 'a [km]';
                app.US.e = 'e';
                app.US.i = 'i [°]';
                app.US.LAN = 'LAN [°]';
                app.US.AP = 'AP [°]';
                app.US.f = 'f [°]';
                app.US.TL = 'TL [°]';
                app.US.AL = 'AL [°]';
                app.US.LP = 'LP [°]';
                app.US.r = 'r [km]';
                app.US.Phi = 'Phi [°]';
                app.US.Theta = 'Theta [°]';
                app.US.x3D = 'x3D [km]';
                app.US.y3D = 'y3D [km]';
                app.US.z3D = 'z3D [km]';
                app.US.Vges = 'Vges [km/s]';
                app.US.t = 't [d]';
                app.US.altitude = 'altitude [km]';
                app.US.epsilon = 'epsilon [m^2/s^2]';
                
                % List of graphs initialized at startup
                app.AddPlotArray = ["t" "Vges" "t,Vges" "tVges" "linear" "linear"
                                    "t" "a" "t,a" "ta" "linear" "linear"
                                    "t" "e" "t,e" "te" "linear" "linear"
                                    "t" "i" "t,i" "ti" "linear" "linear"
                                    "t" "LAN" "t,LAN" "tLAN" "linear" "linear"
                                    "t" "AP" "t,AP" "tAP" "linear" "linear"
                                    "t" "f" "t,f" "tf" "linear" "linear"
                                    "t" "TL" "t,TL" "tTL" "linear" "linear"
                                    "t" "AL" "t,AL" "tAL" "linear" "linear"
                                    "t" "LP" "t,LP" "tLP" "linear" "linear"
                                    "t" "epsilon" "t,epsilon" "tepsilon" "linear" "linear"];
                
                for ii = 1 : (size(app.AddPlotArray,1))
                    app.xax = app.AddPlotArray(ii,1);
                    app.yax = app.AddPlotArray(ii,2);
                    app.XScale = app.AddPlotArray(ii,5);
                    app.YScale = app.AddPlotArray(ii,6);
                    CreatePlot(app)
                end
                
                % EditfieldSettings
                app.HeightEditField.Limits = [app.hfinal Inf];
                
                app.DataChangedLamp.Color = 'red';
                app.RunOrbitSimulationButton.Enable = 'on';
                set(app.UIFigure, 'pointer', oldpointer)
                
%              catch
%                  closereq()
%              end
        end

        % Button pushed function: RunOrbitSimulationButton
        function RunOrbitSimulationButtonPushed(app, event)
             app.RunOrbitSimulationButton.Enable = 'off';                       
             try
                % Bestimmung des ballistischen Koeffizienten
                if strcmp(app.atmosphere,'None')
                    bV = num2str(1);
                else
                    if strcmp(app.SpecOption,'Cubesat')
                        % Bestimmung des ballistischen Koeffizienten aus Cubesat            
                        bV{1} = num2str(app.Mass / (app.Cd *(app.ObjWidth*1e-2*app.ObjHeight*1e-2))); % converts string of BallisticCoefficient to cell array for ECP
                    elseif strcmp(app.SpecOption,'Cubesat with Dragsail')
                        % Bestimmung des ballistischen Koeffizienten aus Cubesat und Dragsailschritten
                        bVu(1) = app.ObjWidth*1e-2*app.ObjHeight*1e-2;
                        if app.Stepnumber == 1
                            bVu(2) = app.MinProjDragsailArea;
                        elseif app.Stepnumber == 2
                            bVu(2) = app.MinProjDragsailArea;
                            bVu(3) = app.MaxProjDragsailArea;
                        elseif app.Stepnumber > 2
                            bVu(2) = app.MinProjDragsailArea;
                            for j=3 : app.Stepnumber
                                Stepsize = (app.MaxProjDragsailArea-app.MinProjDragsailArea)/(app.Stepnumber-1);
                                bVu(j) = app.MinProjDragsailArea+(j-2)*Stepsize;
                            end
                            bVu(app.Stepnumber+1) = app.MaxProjDragsailArea;
                        end
                        bVu = app.Mass ./ (app.Cd.*bVu);
                        bV = cell(1, numel(bVu));
                        for k = 1:numel(bVu)
                            bV{k} = num2str(bVu(k));
                        end
                    else
                        bV = strsplit(app.bInput); % converts string of BallisticCoefficient to cell array
                    end
                end
                if strcmp(app.holds,'off')

                    ClearPlots(app)

                    % Deletes additional Tables
                    if app.SimN > 1 && strcmp(app.holds,'off')
                        fn = fieldnames(app.Tab);
                        for k=1:numel(fn)
                            delete(app.Tab.(fn{k}));
                            delete(app.Table.(fn{k}));
                        end
                    end
                    app.trGroundPlot = {};
                    app.trhfinalPlot = {};
                    app.SimN = 0;
                    app.Orbit3DPlot = {};
                    app.trPlot = {};
                    app.Orbit3DPlotList = {};
                    app.LegendArray = {};
                end
                
                for in=1 : size(bV,2) % Loop for different BallisticCoefficients
                                
                    app.SimN = app.SimN + 1;
                    app.b =str2double(bV(in)); % converts strings to numeric values
        
                    % Create OrbitalParameter Structure
                    if strcmp(app.RefSys,'Spherical Coordinates') 
                        rs = 1;
                        Relips = sqrt((app.Ra*cos(app.Theta0))^2+(app.Rb*sin(app.Theta0))^2);    % WGS84 Ellipsoid radius [m]
                        app.r0 = app.h0 + Relips;
                        rV = [app.r0;app.Phi0;app.Theta0];
                        vV = [app.rDot;app.PhiDot;app.ThetaDot];
                        OP = struct('RS',rs,'rV',rV,'vV',vV); % Orbit Parameters 
                    elseif strcmp(app.RefSys,'Orbital Elements')
                        rs = 2;
                        if app.e == 0 && (app.i == 0||app.i == 180)
                            addN = 'TrueLon';
                            addV = app.TL;                   
                        elseif app.e == 0 && app.i ~= 0
                            addN = 'arglat';
                            addV = app.AL;                    
                        elseif (app.i == 0||app.i == 180) && app.e ~= 0
                            addN = 'lonper';
                            addV = app.LP;
                        else
                            addN = '';
                            addV = 0;
                        end                
                        OP = struct('RS',rs,'a',app.a,'e',app.e,'i',app.i,'LAN',app.LAN,'AP',app.AP,'f',app.f,'addN',addN,'addV',addV); % Orbit Parameters 
                        if OP.e  == 0 || OP.i == 0
                            [r_ijk,~] = keplerian2ijk(OP.a,OP.e,OP.i,OP.LAN,OP.AP,OP.f,OP.addN,OP.addV);
                        else
                            [r_ijk,~] = keplerian2ijk(OP.a,OP.e,OP.i,OP.LAN,OP.AP,OP.f);
                        end
                        rstart = norm(r_ijk);
                        Theta0start = asin(r_ijk(3)/rstart);
                        Relips = sqrt((app.Ra*cos(Theta0start))^2+(app.Rb*sin(Theta0start))^2);    % WGS84 Ellipsoid radius [m]
                        height = rstart-Relips;
                        if height <= app.hfinal || isnan(height)                
                            uialert(app.UIFigure,'Simulation can not be started! Initial height is less than zero or final height!', 'Orbitsimulation','Icon','warning'); 
                            app.RunOrbitSimulationButton.Enable = 'on';
                            return
                        end
                    end 
                    
                    % Create AtmosphericData Structure
                    Years = year(app.InitialDate);
                    Months = month(app.InitialDate);
                    Days = day(app.InitialDate);
                    app.DateTime=datetime(Years,Months,Days,app.Hours,app.Minutes,app.Seconds);            
                    if strcmp(app.solarflux,'Low')
                        f107 = 70;
                        f107a = 70;          
                    elseif strcmp(app.solarflux,'Medium')        
                        f107 = 140;
                        f107a = 140;          
                    elseif strcmp(app.solarflux,'High')        
                        f107 = 380;
                        f107a = 380;         
                    end
                    if strcmp(app.aph,'Low')
                        aphV = 0;         
                    elseif strcmp(app.aph,'Medium')        
                        aphV = 15;        
                    elseif strcmp(app.aph,'High')        
                        aphV = 300;        
                    end
                    AtmospericData = struct('DateTime',app.DateTime,'atmosphere',app.atmosphere,'f107',f107,'f107a',f107a,'aphV',aphV);
                    
                    app.OS = struct('b',app.b,'LtD',app.LtD,'Mass',app.Mass,'Cd',app.Cd); % Object Specifications
                    
                    SS = struct('td',app.td,'hfinal',app.hfinal,'stepsize',app.stepsize,'RelTol',app.RelTol,'AbsTol',app.AbsTol,'ODE',app.ODE); % Simulation Specifications  
                    
                    [app.SimOut,rhoV] = SpaceDebrisSimulatorX3D(AtmospericData,app.OS,SS,OP,app.GravityModel); % Calls Simulation function and post processing
                    
                    plotting(app,app.SimOut,rhoV);
                
                end

%                 ClearPlotsOrbitSimulationButtonGroupSelectionChanged(app)
         
                uialert(app.UIFigure,'Die Simulations wurde erfolgreich abgeschlossen', 'Orbitsimulation','Icon','success'); 
                app.RunOrbitSimulationButton.Enable = 'on';
                app.DataChangedLamp.Color = 'green';
            catch ME
                uialert(app.UIFigure,ME.message, 'Orbitsimulation','Icon','error');
                app.RunOrbitSimulationButton.Enable = 'on';
            end
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.h0 = app.HeightEditField.Value;            
        end

        % Value changed function: SimulationTimeEditField
        function SimulationTimeEditFieldValueChanged(app, event)
            app.td = app.SimulationTimeEditField.Value;            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq()
        end

        % Value changed function: AtmosphereModelsDropDown
        function AtmosphereModelsDropDownValueChanged(app, event)
            app.atmosphere = app.AtmosphereModelsDropDown.Value; 
            if strcmp(app.atmosphere,'Exponential')
                app.SolarFluxDropDown.Enable = 'off';
                app.SolarFluxDropDownLabel.Enable = 'off';
                app.aphDropDown.Enable = 'off';
                app.aphDropDownLabel.Enable = 'off';
                SpecOptionDropDownValueChanged(app)
                app.LifttoDragratioEditField.Enable = 'on';
                app.LifttoDragratioEditFieldLabel.Enable = 'on';
                app.LitftoDragratioUnitLabel.Enable = 'on';
            elseif strcmp(app.atmosphere,'nrlmsise00')
                app.SolarFluxDropDown.Enable = 'on';
                app.SolarFluxDropDownLabel.Enable = 'on';
                app.aphDropDown.Enable = 'on';
                app.aphDropDownLabel.Enable = 'on';
                SpecOptionDropDownValueChanged(app)
                app.LifttoDragratioEditField.Enable = 'on';
                app.LifttoDragratioEditFieldLabel.Enable = 'on';
                app.LitftoDragratioUnitLabel.Enable = 'on';                
            elseif strcmp(app.atmosphere,'None')
                app.SolarFluxDropDown.Enable = 'off';
                app.SolarFluxDropDownLabel.Enable = 'off';
                app.aphDropDown.Enable = 'off';
                app.aphDropDownLabel.Enable = 'off';
                app.LifttoDragratioEditField.Enable = 'off';
                app.LifttoDragratioEditFieldLabel.Enable = 'off';
                app.LitftoDragratioUnitLabel.Enable = 'off';
            end
        end

        % Value changed function: SolarFluxDropDown
        function SolarFluxDropDownValueChanged(app, event)
            app.solarflux = app.SolarFluxDropDown.Value;    
        end

        % Value changed function: FinalHeightEditField
        function FinalHeightEditFieldValueChanged(app, event)
            app.hfinal = app.FinalHeightEditField.Value; 
            app.HeightEditField.Limits = [app.hfinal Inf];
        end

        % Value changed function: LifttoDragratioEditField
        function LifttoDragratioEditFieldValueChanged(app, event)
            app.LtD = app.LifttoDragratioEditField.Value;  
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: InclinationEditField
        function InclinationEditFieldValueChanged(app, event)
            app.i = app.InclinationEditField.Value;
            eccincchange(app)
        end

        % Selection changed function: ClearPlotsOrbitSimulationButtonGroup
        function ClearPlotsOrbitSimulationButtonGroupSelectionChanged(app, event)
            app.ClearPlotsOrbitSimulationButtonGroup.SelectedObject
            if app.noButton.Value  
                app.holds = 'on';
            elseif app.yesButton.Value
                app.holds = 'off'; 
            end
        end

        % Value changed function: LANEditField
        function LANEditFieldValueChanged2(app, event)
            app.LAN = app.LANEditField.Value;
        end

        % Value changed function: EccentricityEditField
        function EccentricityEditFieldValueChanged(app, event)
            app.e = app.EccentricityEditField.Value;
            eccincchange(app) 
        end

        % Value changed function: SemimajorAxisEditField
        function SemimajorAxisEditFieldValueChanged(app, event)
            app.a = app.SemimajorAxisEditField.Value;
        end

        % Value changed function: ReferenceSystemDropDown
        function ReferenceSystemDropDownValueChanged(app, event)
            app.RefSys = app.ReferenceSystemDropDown.Value;
            if strcmp(app.RefSys,'Orbital Elements')   
                % Sets Spherical Coordinates visibility off
                app.HeightEditField.Visible = 'off';
                app.HeightEditFieldLabel.Visible = 'off';
                app.HeightUnitLabel.Visible = 'off';
                app.PhiEditField.Visible = 'off'; 
                app.PhiEditFieldLabel.Visible = 'off';
                app.PhiUnitLabel.Visible = 'off';
                app.ThetaEditField.Visible = 'off'; 
                app.ThetaEditFieldLabel.Visible = 'off';
                app.ThetaUnitLabel.Visible = 'off';
                app.rDotEditField.Visible = 'off';
                app.rDotEditFieldLabel.Visible = 'off';
                app.rDotUnitLabel.Visible = 'off';
                app.PhiDotEditField.Visible = 'off'; 
                app.PhiDotEditFieldLabel.Visible = 'off';                                     
                app.PhiDotUnitLabel.Visible = 'off';
                app.ThetaDotEditField.Visible = 'off'; 
                app.ThetaDotEditFieldLabel.Visible = 'off';
                app.ThetaDotUnitLabel.Visible = 'off';
                % Sets Orbital Elements visibility on
                app.SemimajorAxisEditField.Visible = 'on';
                app.SemimajorAxisEditFieldLabel.Visible = 'on';
                app.SemimajoraxisUnitLabel.Visible = 'on';
                app.InclinationEditField.Visible = 'on';
                app.InclinationEditFieldLabel.Visible = 'on';              
                app.InclinationUnitLabel.Visible = 'on';
                app.EccentricityEditField.Visible = 'on';
                app.EccentricityEditFieldLabel.Visible = 'on';
                app.EccentricityUnitLabel.Visible = 'on';
                app.LANEditField.Visible = 'on';
                app.LANLabel.Visible = 'on';              
                app.LANUnitLabel.Visible = 'on';
                app.APEditField.Visible = 'on';
                app.APLabel.Visible = 'on';
                app.APUnitLabel.Visible = 'on';
                app.TrueAnomalyEditField.Visible = 'on';
                app.TrueAnomalyEditFieldLabel.Visible = 'on';              
                app.TrueAnomalyUnitLabel.Visible = 'on';
                app.TLEditField.Visible = 'on';
                app.TLEditFieldLabel.Visible = 'on';              
                app.TLUnitLabel.Visible = 'on';
                app.ALEditField.Visible = 'on';
                app.ALLabel.Visible = 'on';              
                app.ALUnitLabel.Visible = 'on';
                app.LPEditField.Visible = 'on';
                app.LPEditFieldLabel.Visible = 'on';              
                app.LPUnitLabel.Visible = 'on';
            elseif strcmp(app.RefSys,'Spherical Coordinates')
                % Sets Spherical Coordinates visibility on
                app.HeightEditField.Visible = 'on';
                app.HeightEditFieldLabel.Visible = 'on';
                app.HeightUnitLabel.Visible = 'on';
                app.PhiEditField.Visible = 'on';
                app.PhiEditFieldLabel.Visible = 'on';              
                app.PhiUnitLabel.Visible = 'on';
                app.ThetaEditField.Visible = 'on';
                app.ThetaEditFieldLabel.Visible = 'on';              
                app.ThetaUnitLabel.Visible = 'on';
                app.rDotEditField.Visible = 'on';
                app.rDotEditFieldLabel.Visible = 'on';
                app.rDotUnitLabel.Visible = 'on';
                app.PhiDotEditField.Visible = 'on'; 
                app.PhiDotEditFieldLabel.Visible = 'on';
                app.PhiDotUnitLabel.Visible = 'on';
                app.ThetaDotEditField.Visible = 'on'; 
                app.ThetaDotEditFieldLabel.Visible = 'on';
                app.ThetaDotUnitLabel.Visible = 'on';
                % Sets Orbital Elements visibility off
                app.SemimajorAxisEditField.Visible = 'off';
                app.SemimajorAxisEditFieldLabel.Visible = 'off';
                app.SemimajoraxisUnitLabel.Visible = 'off';
                app.InclinationEditField.Visible = 'off';
                app.InclinationEditFieldLabel.Visible = 'off';              
                app.InclinationUnitLabel.Visible = 'off';
                app.EccentricityEditField.Visible = 'off';
                app.EccentricityEditFieldLabel.Visible = 'off';
                app.EccentricityUnitLabel.Visible = 'off';
                app.LANEditField.Visible = 'off';
                app.LANLabel.Visible = 'off';              
                app.LANUnitLabel.Visible = 'off';
                app.APEditField.Visible = 'off';
                app.APLabel.Visible = 'off';
                app.APUnitLabel.Visible = 'off';
                app.TrueAnomalyEditField.Visible = 'off';
                app.TrueAnomalyEditFieldLabel.Visible = 'off';              
                app.TrueAnomalyUnitLabel.Visible = 'off';
                app.TLEditField.Visible = 'off';
                app.TLEditFieldLabel.Visible = 'off';              
                app.TLUnitLabel.Visible = 'off';
                app.ALEditField.Visible = 'off';
                app.ALLabel.Visible = 'off';              
                app.ALUnitLabel.Visible = 'off';
                app.LPEditField.Visible = 'off';
                app.LPEditFieldLabel.Visible = 'off';              
                app.LPUnitLabel.Visible = 'off';                
            end
            eccincchange(app)
        end

        % Value changed function: aphDropDown
        function aphDropDownValueChanged(app, event)
            app.aph = app.aphDropDown.Value;  
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            app.stepsize = app.StepSizeEditField.Value;            
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            app.Theta0 = app.ThetaEditField.Value;  
        end

        % Value changed function: ThetaDotEditField
        function ThetaDotEditFieldValueChanged(app, event)
            app.ThetaDot = app.ThetaDotEditField.Value; 
        end

        % Value changed function: rDotEditField
        function rDotEditFieldValueChanged(app, event)
            app.rDot = app.rDotEditField.Value;    
        end

        % Value changed function: PhiDotEditField
        function PhiDotEditFieldValueChanged(app, event)
            app.PhiDot = app.PhiDotEditField.Value;     
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: InitialDateDatePicker
        function InitialDateDatePickerValueChanged(app, event)
            app.InitialDate = app.InitialDateDatePicker.Value; 
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: HoursEditField
        function HoursEditFieldValueChanged(app, event)
            app.Hours = app.HoursEditField.Value;  
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: MinutesEditField
        function MinutesEditFieldValueChanged(app, event)
            app.Minutes = app.MinutesEditField.Value;  
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: SecondsEditField
        function SecondsEditFieldValueChanged(app, event)
            app.Seconds = app.SecondsEditField.Value;    
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: TLEditField
        function TLEditFieldValueChanged(app, event)
            app.TL = app.TLEditField.Value;   
        end

        % Value changed function: ALEditField
        function ALEditFieldValueChanged(app, event)
            app.AL = app.ALEditField.Value;            
        end

        % Value changed function: LPEditField
        function LPEditFieldValueChanged(app, event)
            app.LP = app.LPEditField.Value; 
        end

        % Value changed function: RelErrorTolEditField
        function RelErrorTolEditFieldValueChanged(app, event)
            app.RelTol = app.RelErrorTolEditField.Value;            
        end

        % Value changed function: AbsErrorTolEditField
        function AbsErrorTolEditFieldValueChanged(app, event)
            app.AbsTol = app.AbsErrorTolEditField.Value;            
        end

        % Value changed function: GravityModelDropDown
        function GravityModelDropDownValueChanged(app, event)
            app.GravityModel = app.GravityModelDropDown.Value;   
        end

        % Value changed function: BallisticCoefficientEditField
        function BallisticCoefficientEditFieldValueChanged(app, event)
            app.bInput = app.BallisticCoefficientEditField.Value;
            app.DataChangedLamp.Color = 'red';
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            SaveData(app,'save')
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            title = 'Simulation Settings';
            if isempty(app.filenames)
                filter = {'*.ini'};
                [file, path,indx] = uiputfile(filter,title) ;
                app.filenames = fullfile(path,file); 
            else
                indx = 1;
            end
            
            if indx ~= 0 
                ini = IniConfig();
                ini.AddSections({'Orbit Parameters', 'Atmosphere Model','Time and Date','Gravity Model','Simulation Specifications','Object Parameters','Clear Plots','Graphs'})
                ini.AddKeys('Orbit Parameters', {'Reference System','Height', 'Phi','Theta','rDot','PhiDot','ThetaDot','Semi-major Axis','Eccentricity','Inclination','LAN','AP','True Anomaly','TL','AL','LP'}, {app.RefSys,app.h0,app.Phi0,app.Theta0,app.rDot,app.PhiDot,app.ThetaDot,app.a,app.e,app.i,app.LAN,app.AP,app.f,app.TL,app.AL,app.LP})
                ini.AddKeys('Atmosphere Model', {'Atmosphere Models','Solar Flux','aph'}, {app.atmosphere,app.solarflux,app.aph})
                Years = year(app.InitialDate);
                Months = month(app.InitialDate);
                Days = day(app.InitialDate);
                ini.AddKeys('Time and Date', {'Years','Months','Days','Hours','Minutes','Seconds'}, {Years,Months,Days,app.Hours,app.Minutes,app.Seconds})
                ini.AddKeys('Gravity Model', 'Gravity Model', app.GravityModel)
                ini.AddKeys('Simulation Specifications', {'ODE','Simulation Time','Final Height','Step Size','Rel. Error Tol.','Abs. Error Tol.'}, {app.ODE,app.td,app.hfinal,app.stepsize,app.RelTol,app.AbsTol})
                ini.AddKeys('Object Parameters', {'Spec. Option','Ballistic Coefficient','Mass','cd','Lift to Drag ratio','Evaluation Mode','Width','Height','Depth','Min Proj. Dragsail Area','Max Proj. Dragsail Area','Stepnumber'}, {app.SpecOption,app.bInput,app.Mass,app.Cd,app.LtD,app.ModeECP,app.ObjWidth,app.ObjHeight,app.ObjDepth,app.MinProjDragsailArea,app.MaxProjDragsailArea,app.Stepnumber})
                ini.AddKeys('Clear Plots', {'Hold onff Orbit Sim','Hold onff Flux Sim'},{app.holds,app.holdsECP})
                ini.AddKeys('Graphs', 'some_key1') % add Graphs settings!!!!!!!!!!!!!!!!!!!!!!!!
                ini.WriteFile(app.filenames)
                app.filenames = '';
                Message = append('Settings succesfully saved!');
                uialert(app.UIFigure,Message, 'Simulation Settings','Icon','success');
            end           
        end

        % Menu selected function: LoadSettingsMenu
        function LoadSettingsMenuSelected(app, event)
            if isempty(app.filenames)
                filter = '*.ini';
                [file,path] = uigetfile(filter);
                if isequal(file, 0) || isequal(path, 0)
                    return;
                end
                app.filenames = fullfile(path,file);
            else
                indx = 1;
            end
                
            if indx == 1 || ~isequal(file,0)    
                ini = IniConfig();
                ini.ReadFile(app.filenames)
                
                app.ReferenceSystemDropDown.Value = ini.GetValues('Orbit Parameters', 'Reference System');
                ReferenceSystemDropDownValueChanged(app)
                app.HeightEditField.Value = ini.GetValues('Orbit Parameters', 'Height');
                HeightEditFieldValueChanged(app)
                app.PhiEditField.Value = ini.GetValues('Orbit Parameters', 'Phi');
                PhiEditFieldValueChanged(app)
                app.ThetaEditField.Value = ini.GetValues('Orbit Parameters', 'Theta');
                ThetaEditFieldValueChanged(app)
                app.rDotEditField.Value = ini.GetValues('Orbit Parameters', 'rDot');
                rDotEditFieldValueChanged(app)
                app.PhiDotEditField.Value = ini.GetValues('Orbit Parameters', 'PhiDot');
                PhiDotEditFieldValueChanged(app)
                app.ThetaDotEditField.Value = ini.GetValues('Orbit Parameters', 'ThetaDot');
                ThetaDotEditFieldValueChanged(app)
                app.SemimajorAxisEditField.Value = ini.GetValues('Orbit Parameters', 'Semi-major Axis');
                SemimajorAxisEditFieldValueChanged(app)
                app.EccentricityEditField.Value = ini.GetValues('Orbit Parameters', 'Eccentricity');
                EccentricityEditFieldValueChanged(app)
                app.InclinationEditField.Value = ini.GetValues('Orbit Parameters', 'Inclination');
                InclinationEditFieldValueChanged(app)
                app.LANEditField.Value = ini.GetValues('Orbit Parameters', 'LAN');
                LANEditFieldValueChanged2(app)
                app.APEditField.Value = ini.GetValues('Orbit Parameters', 'AP');
                APEditFieldValueChanged(app)
                app.TrueAnomalyEditField.Value = ini.GetValues('Orbit Parameters', 'True Anomaly');
                TrueAnomalyEditFieldValueChanged(app)
                app.TLEditField.Value = ini.GetValues('Orbit Parameters', 'TL');
                TLEditFieldValueChanged(app)
                app.ALEditField.Value = ini.GetValues('Orbit Parameters', 'AL');
                ALEditFieldValueChanged(app)
                app.LPEditField.Value = ini.GetValues('Orbit Parameters', 'LP');
                LPEditFieldValueChanged(app)
                
                app.SpecOptionDropDown.Value = ini.GetValues('Object Parameters', 'Spec. Option');
                SpecOptionDropDownValueChanged(app)
                app.BallisticCoefficientEditField.Value = num2str(ini.GetValues('Object Parameters', 'Ballistic Coefficient'));
                BallisticCoefficientEditFieldValueChanged(app)
                app.MassEditField.Value = ini.GetValues('Object Parameters', 'Mass');
                MassEditFieldValueChanged(app)
                app.CdEditField.Value = ini.GetValues('Object Parameters', 'cd');
                CdEditFieldValueChanged(app)
                app.LifttoDragratioEditField.Value = ini.GetValues('Object Parameters', 'Lift to Drag ratio');
                LifttoDragratioEditFieldValueChanged(app)
                app.ModeECP = ini.GetValues('Object Parameters', 'Evaluation Mode');
                if  app.ModeECP == 1
                    app.ComplexButton.Value = 1;
                elseif app.ModeECP == 2
                    app.ReducedButton.Value = 1;
                end
                app.ObjWidthEditField.Value = ini.GetValues('Object Parameters', 'Width');
                ObjWidthEditFieldValueChanged(app)
                app.ObjHeightEditField.Value = ini.GetValues('Object Parameters', 'Height');
                ObjHeightEditFieldValueChanged(app)
                app.ObjDepthEditField.Value = ini.GetValues('Object Parameters', 'Depth');
                ObjDepthEditFieldValueChanged(app)
                app.MinProjDragsailAreaEditField.Value = ini.GetValues('Object Parameters', 'Min Proj. Dragsail Area');
                MinProjDragsailAreaEditFieldValueChanged(app)
                app.MaxProjDragsailAreaEditField.Value = ini.GetValues('Object Parameters', 'Max Proj. Dragsail Area');
                MaxProjDragsailAreaEditFieldValueChanged(app)
                app.StepnumberEditField.Value = ini.GetValues('Object Parameters', 'Stepnumber');
                StepnumberEditFieldValueChanged(app)
                
                app.holds = ini.GetValues('Clear Plots', 'Hold onff Orbit Sim');
                if  strcmp(app.holds,'on') 
                    app.yesButton.Value = 1;
                elseif strcmp(app.holds,'off') 
                    app.yesButton.Value = 0; 
                end

                app.holdsECP = ini.GetValues('Clear Plots', 'Hold onff Flux Sim');
                if  strcmp(app.holdsECP,'on') 
                    app.yesButton.Value = 1;
                elseif strcmp(app.holdsECP,'off') 
                    app.yesButton.Value = 0; 
                end
                
                app.AtmosphereModelsDropDown.Value = ini.GetValues('Atmosphere Model', 'Atmosphere Models');
                AtmosphereModelsDropDownValueChanged(app)
                app.SolarFluxDropDown.Value = ini.GetValues('Atmosphere Model', 'Solar Flux');
                SolarFluxDropDownValueChanged(app)
                app.aphDropDown.Value = ini.GetValues('Atmosphere Model', 'aph');
                aphDropDownValueChanged(app)
                
                Years = ini.GetValues('Time and Date', 'Years');
                Months = ini.GetValues('Time and Date', 'Months');
                Days = ini.GetValues('Time and Date', 'Days'); 
                app.InitialDateDatePicker.Value = datetime(Years,Months,Days);
                InitialDateDatePickerValueChanged(app)
                app.HoursEditField.Value = ini.GetValues('Time and Date', 'Hours');
                HoursEditFieldValueChanged(app)
                app.MinutesEditField.Value = ini.GetValues('Time and Date', 'Minutes');
                MinutesEditFieldValueChanged(app)
                app.SecondsEditField.Value = ini.GetValues('Time and Date', 'Seconds');
                SecondsEditFieldValueChanged(app)
                app.DateTime=datetime(Years,Months,Days,app.Hours,app.Minutes,app.Seconds);

                app.GravityModelDropDown.Value = ini.GetValues('Gravity Model', 'Gravity Model');
                GravityModelDropDownValueChanged(app)
                
                app.ODEDropDown.Value = num2str(ini.GetValues('Simulation Specifications', 'ODE'));
                ODEDropDownValueChanged(app)
                app.SimulationTimeEditField.Value = ini.GetValues('Simulation Specifications', 'Simulation Time');
                SimulationTimeEditFieldValueChanged(app)
                app.FinalHeightEditField.Value = ini.GetValues('Simulation Specifications', 'Final Height');
                FinalHeightEditFieldValueChanged(app)
                app.StepSizeEditField.Value = ini.GetValues('Simulation Specifications', 'Step Size');
                StepSizeEditFieldValueChanged(app)
                app.RelErrorTolEditField.Value = str2double(ini.GetValues('Simulation Specifications', 'Rel. Error Tol.'));                               
                RelErrorTolEditFieldValueChanged(app)
                app.AbsErrorTolEditField.Value = str2double(ini.GetValues('Simulation Specifications', 'Abs. Error Tol.'));
                AbsErrorTolEditFieldValueChanged(app)
            end
        end

        % Menu selected function: LoadTLEMenu
        function LoadTLEMenuSelected(app, event)
            %!!!!!!!!!!!!!!!!!!!!!!!!!!!!! ballistic coefficient umrechnen
            filter = '*.txt';
            [file,path] = uigetfile(filter);
            filename = fullfile(path,file);
            if ~isequal(file,0)
                [app.a, app.e, app.i, app.LAN, app.AP, app.f] = Two_line_Element(filename);
                %Orbital Elements
                app.ReferenceSystemDropDown.Value = 'Orbital Elements';
                app.RefSys = 'Orbital Elements'; 
                ReferenceSystemDropDownValueChanged(app, event)
                app.InclinationEditField.Value = app.i;
                app.SemimajorAxisEditField.Value = app.a;
                app.LANEditField.Value = app.LAN;
                app.EccentricityEditField.Value = app.e;            
                app.APEditField.Value = app.AP;
                app.TrueAnomalyEditField.Value = app.f;
                eccincchange(app)
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Black/White')
                if app.ShowEarthCheckBox.Value == false
                    set(app.EarthVis,'visible','off')
                    set(app.EarthVis2,'visible','off')
                elseif app.ShowEarthCheckBox.Value == true
                    set(app.EarthVis,'visible','off')
                    set(app.EarthVis2,'visible','on')                
                end
                set(app.Orbit3DTab,'BackgroundColor',[0.94,0.94,0.94])
                set(app.ButtonGroup, 'BackgroundColor',[0.94,0.94,0.94]) 
                set(app.ShowEarthCheckBox, 'FontColor','k') 
                set(app.Orbit3DLeg,'Color',[0.94,0.94,0.94],'TextColor','k')               
                set(app.ColoredButton,'FontColor','k')
                set(app.BlackWhiteButton,'FontColor','k')
                space_color = 'w';
                axisColor = 'k';
                set(app.UIAxes5,'Color','w','GridColor','k','XColor',axisColor,'YColor',axisColor,'ZColor',axisColor,'Color', space_color)
            elseif strcmp(selectedButton.Text,'Colored') 
                if app.ShowEarthCheckBox.Value == false
                    set(app.EarthVis,'visible','off')
                    set(app.EarthVis2,'visible','off')
                elseif app.ShowEarthCheckBox.Value == true
                    set(app.EarthVis,'visible','on')
                    set(app.EarthVis2,'visible','off')                
                end
                app.Orbit3DTab.BackgroundColor = 'k';
                app.ButtonGroup.BackgroundColor = 'k';
                set(app.ShowEarthCheckBox, 'FontColor',[0.588, 0.588, 0.588])
                set(app.Orbit3DLeg,'Color','k','TextColor',[0.588, 0.588, 0.588])
                set(app.ButtonGroup, 'BackgroundColor','k'); 
                set(app.ColoredButton,'FontColor',[0.588, 0.588, 0.588])
                set(app.BlackWhiteButton,'FontColor',[0.588, 0.588, 0.588])
                space_color = 'k';
                axisColor = [0.588, 0.588, 0.588];
                set(app.UIAxes5,'Color','k','GridColor',[1, 1, 1],'XColor',axisColor,'YColor',axisColor,'ZColor',axisColor,'Color', space_color)
            end
        end

        % Value changed function: ShowEarthCheckBox
        function ShowEarthCheckBoxValueChanged(app, event)
            value = app.ShowEarthCheckBox.Value;
            if value == false
                set(app.EarthVis,'visible','off')
                set(app.EarthVis2,'visible','off')
            elseif app.ColoredButton.Value == true
                set(app.EarthVis,'visible','on')
                set(app.EarthVis2,'visible','off')
            elseif app.BlackWhiteButton.Value == true
                set(app.EarthVis,'visible','off')
                set(app.EarthVis2,'visible','on')                
            end
        end

        % Menu selected function: SaveDataasMenu
        function SaveDataasMenuSelected(app, event)
            SaveData(app,'saveas')
        end

        % Value changed function: ODEDropDown
        function ODEDropDownValueChanged(app, event)
            app.ODE = app.ODEDropDown.Value;  
            if strcmp(app.ODE,'87')
                app.StepSizeEditField.Enable = 'off';
                app.StepSizeEditFieldLabel.Enable = 'off';
                app.StepSizeUnitLabel.Enable = 'off';
                app.StepSizeEditField.Value = 0;
                app.stepsize = 0;
            else
                app.StepSizeEditField.Enable = 'on';
                app.StepSizeEditFieldLabel.Enable = 'on';
                app.StepSizeUnitLabel.Enable = 'on';
            end
        end

        % Menu selected function: AddPlotMenu
        function AddPlotMenuSelected(app, event)
            [app.xax,app.yax,app.XScale,app.YScale] = NewPlotSettings(app.opt_list);    % Call NewPlotSettings function to choose variables for the new plot
            [test,~] = find(strcmp(app.AddPlotArray,append(app.xax,',',app.yax)));
            if app.xax ~= 0 && isempty(test)                                            % only execute if NewPlotSettings was not aborted
                app.AddPlotArray(end+1,1) = app.xax;                                    % add choosen variable to array of created plots (X-Axis)
                app.AddPlotArray(end,2) = app.yax;                                      % add choosen variable to array of created plots (Y-Axis)
                app.AddPlotArray(end,3) = append(app.xax,",",app.yax);                  % Entry for title or optionlist in third columnn
                app.AddPlotArray(end,4) = append(app.xax,app.yax);                      % Entry for object handling in fourth column
                app.AddPlotArray(end,5) = app.XScale;                                   % Entry for x-axis scale
                app.AddPlotArray(end,6) = app.YScale;                                   % Entry for y-axis scale
                CreatePlot(app)                                                         % make UIAxes control buttons visible again
            elseif app.xax ~= 0 && ~isempty(test)
                uialert(app.UIFigure,'Graph already exists!', 'Orbitsimulation','Icon','warning');
            end
        end

        % Menu selected function: DeletePlotMenu
        function DeletePlotMenuSelected(app, event)
            app.DelPlot = Delete_Plot(app.AddPlotArray(:,3));
            if app.DelPlot ~= 0
                [delrow,~] = find(strcmp(app.AddPlotArray,app.DelPlot));
                app.AddPlotArray(delrow,:) = [];
                app.DelPlot = regexprep(app.DelPlot,'(\w+),(\w+)','$1$2');
                delete(app.notdefinedLabel.(app.DelPlot))
                delete(app.UIAxesCreate.(app.DelPlot))
                delete(app.PlotTab.(app.DelPlot))               
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closereq()            
        end

        % Button pushed function: RunFluxSimulationButton
        function RunFluxSimulationButtonPushed(app, event)
            %app.RunFluxSimulationButton.Enable = 'off';
            
            redColor = [1 0 0];
            Years = year(app.InitialDate);
            if app.SimN == 0
                uialert(app.UIFigure,'No Data found!', 'ECP','Icon','warning');
                app.RunFluxSimulationButton.Enable = 'on';
                return
            elseif Years < 2023
                choice = questdlg('The time database is only valid starting from 2023. Since the current date is before that year, values from January 1, 2023, will be used!', ...
                  'Warnung', ...
                  'Continue', 'Abort', 'Continue');
                if strcmp(choice, 'Abort')
                   app.RunFluxSimulationButton.Enable = 'on';
                   return
                end
            elseif isequal(app.DataChangedLamp.Color, redColor)
                choice = uiconfirm(app.UIFigure, 'Orbit Simulation and ECP Input is not consistent! Do you want to Continue?', ...
                   'ECP', 'Icon', 'warning', ...
                   'Options', {'OK', 'Abort'}, ...
                   'DefaultOption', 'Abort');

                if strcmp(choice, 'Abort')
                    return
                end
            end

            % Schreibe alle Daten in ein Cell Array  {'t [d]','r [km]','phi [°]','h [km]','vges [km/s]','a [km]','e []','f [°]','AP [°]','LAN[°]','i [°]'}                       
            Data = cell(app.SimN,1);
            for ii = 1 : app.SimN 
                if ii == 1
                    Data{1} = num2cell(app.SimTable1.Data);
                    if (Data{1}{1,11} < 0.1 || Data{1}{1,7} < 0.0000999) && app.ModeECP == 1 
                        uialert(app.UIFigure,'Invalid Input for MASTER Simulation (Inclination < 0.1° or Eccentricity < 0.0001)!', 'ECP','Icon','warning'); 
                        return
                    end
                else 
                    my_field = strcat('Sim',num2str(ii));
                    Data{ii} = num2cell(app.Table.(my_field).Data);
                    if (Data{ii}{1,7} < 0.1 || Data{ii}{1,11} < 0.0001) && app.ModeECP == 1 
                        uialert(app.UIFigure,'Invalid Input for MASTER Simulation (Inclination < 0.1° or Eccentricity < 0.0001)!', 'ECP','Icon','warning'); 
                        return
                    end
                end
            end
            % Creation of Object Structure
            app.ObjSpec.bInput = app.BallisticCoefficientEditField.Value;
            app.ObjSpec.LtD = app.LifttoDragratioEditField.Value;
            app.ObjSpec.SpecOption = app.SpecOptionDropDown.Value;
            app.ObjSpec.ObjWidth = app.ObjWidthEditField.Value;
            app.ObjSpec.ObjHeight = app.ObjHeightEditField.Value;
            app.ObjSpec.ObjDepth = app.ObjDepthEditField.Value;
            app.ObjSpec.MinProjDragsailArea = app.MinProjDragsailAreaEditField.Value;
            app.ObjSpec.MaxProjDragsailArea = app.MaxProjDragsailAreaEditField.Value;
            app.ObjSpec.Stepnumber = app.StepnumberEditField.Value;
            app.ObjSpec.Mass = app.MassEditField.Value;
            app.ObjSpec.Cd = app.CdEditField.Value;

            % Simulation Arguments   
            app.OrbitSimSpec.ODE = app.ODE;
            app.OrbitSimSpec.stepsize = app.stepsize;
            app.OrbitSimSpec.td = app.td;
            app.OrbitSimSpec.hfinal = app.hfinal;
            app.OrbitSimSpec.RelTol = app.RelTol;
            app.OrbitSimSpec.AbsTol = app.AbsTol;

            % Date
            app.OrbitSimSpec.DateTime = app.DateTime;
            
            % Atmospheric model and Conditions        
            app.OrbitSimSpec.aph = app.aph;
            app.OrbitSimSpec.atmosphere = app.atmosphere;
            app.OrbitSimSpec.solarflux = app.solarflux;
            
            % Gravity Model       
            app.OrbitSimSpec.GravityModel = app.GravityModel;
            
            % Input Orbital elements        
            app.OrbitSimSpec.a = app.a; % semi-major axis
            app.OrbitSimSpec.e = app.e; % eccentricity
            app.OrbitSimSpec.i = app.i; % inclination
            app.OrbitSimSpec.LAN = app.LAN; % longitude of the ascending node
            app.OrbitSimSpec.AP = app.AP; % argument of pericenter 
            app.OrbitSimSpec.f = app.f; % true anomaly
            app.OrbitSimSpec.TL = app.TL; % true longitude
            app.OrbitSimSpec.AL = app.AL; % argument of latitude
            app.OrbitSimSpec.LP = app.LP; % longitude of periapsis
            
            % Spherical Coordinates      
            app.OrbitSimSpec.r0 = app.r0;
            app.OrbitSimSpec.h0 = app.h0;
            app.OrbitSimSpec.Phi0 = app.Phi0;
            app.OrbitSimSpec.Theta0 = app.Theta0;
            app.OrbitSimSpec.rDot = app.rDot;
            app.OrbitSimSpec.PhiDot = app.PhiDot;
            app.OrbitSimSpec.ThetaDot = app.ThetaDot;
            
            app.OrbitSimSpec.RefSys = app.RefSys;

            [AllOrbitTotalResult,Result,ObjectMissionFlux,SigmResult]=ECP(Data,app.SimStartDates,app.ModeECP,app.ObjSpec,app.OrbitSimSpec); % Funktion für die Bestimmung der Kollisionswahscheinlichkeit
            
            ECPPlotting(app,AllOrbitTotalResult,Result,ObjectMissionFlux,SigmResult)
             
            uialert(app.UIFigure,'Die Simulations wurde erfolgreich abgeschlossen', 'Fluxsimulation','Icon','success'); 
            app.RunFluxSimulationButton.Enable = 'on';
        end

        % Value changed function: SpecOptionDropDown
        function SpecOptionDropDownValueChanged(app, event)
            app.SpecOption = app.SpecOptionDropDown.Value;   
            if strcmp(app.SpecOption,'Sphere')
                app.ComplexButton.Enable = 'on';
                app.BallisticCoefficientEditField.Enable = 'on';
                app.BallisticCoefficientUnitLabel.Enable = 'on';
                app.BallisticCoefficientEditFieldLabel.Enable = 'on';
%                 app.MassEditField.Enable = 'on';
%                 app.MassUnitLabel.Enable = 'on';
%                 app.MassEditFieldLabel.Enable = 'on';
%                 app.CdEditField.Enable = 'on';
%                 app.CdUnitLabel.Enable = 'on';
%                 app.CdEditFieldLabel.Enable = 'on'; 
                app.StepnumberEditField.Enable= 'off';
                app.StepnumberEditFieldLabel.Enable= 'off';
                app.StepnumberUnitLabel.Enable= 'off';
                app.MaxProjDragsailAreaEditField.Enable= 'off';
                app.MaxProjDragsailAreaEditFieldLabel.Enable= 'off';
                app.MaxProjDragsailAreaEditFieldUnitLabel.Enable= 'off';
                app.MinProjDragsailAreaEditField.Enable= 'off';
                app.MinProjDragsailAreaEditFieldLabel.Enable= 'off';
                app.MinProjDragsailAreaEditFieldUnitLabel.Enable= 'off';
                app.ObjDepthEditField.Enable= 'off';
                app.ObjDepthEditFieldLabel.Enable= 'off';
                app.ObjDepthUnitLabel.Enable= 'off';
                app.ObjHeightEditField.Enable= 'off';
                app.ObjHeightEditFieldLabel.Enable= 'off';
                app.ObjHeightUnitLabel.Enable= 'off';
                app.ObjWidthEditField.Enable= 'off';
                app.ObjWidthEditFieldLabel.Enable= 'off';
                app.ObjWidthUnitLabel.Enable= 'off';
            elseif strcmp(app.SpecOption,'Cubesat')
                app.ComplexButton.Enable = 'off';
                app.BallisticCoefficientEditField.Enable = 'off';
                app.BallisticCoefficientUnitLabel.Enable = 'off';
                app.BallisticCoefficientEditFieldLabel.Enable = 'off';
%                 app.MassEditField.Enable = 'on';
%                 app.MassUnitLabel.Enable = 'on';
%                 app.MassEditFieldLabel.Enable = 'on';
%                 app.CdEditField.Enable = 'on';
%                 app.CdUnitLabel.Enable = 'on';
%                 app.CdEditFieldLabel.Enable = 'on';  
                app.StepnumberEditField.Enable= 'off';
                app.StepnumberEditFieldLabel.Enable = 'off';
                app.StepnumberUnitLabel.Enable= 'off';
                app.MaxProjDragsailAreaEditField.Enable= 'off';
                app.MaxProjDragsailAreaEditFieldLabel.Enable = 'off';
                app.MaxProjDragsailAreaEditFieldUnitLabel.Enable= 'off';
                app.MinProjDragsailAreaEditField.Enable= 'off';
                app.MinProjDragsailAreaEditFieldLabel.Enable = 'off';
                app.MinProjDragsailAreaEditFieldUnitLabel.Enable= 'off';
                app.ObjDepthEditField.Enable= 'on';
                app.ObjDepthEditFieldLabel.Enable = 'on';
                app.ObjDepthUnitLabel.Enable= 'on';
                app.ObjHeightEditField.Enable= 'on';
                app.ObjHeightEditFieldLabel.Enable = 'on';
                app.ObjHeightUnitLabel.Enable= 'on';
                app.ObjWidthEditField.Enable= 'on';
                app.ObjWidthEditFieldLabel.Enable = 'on';
                app.ObjWidthUnitLabel.Enable= 'on';
            elseif strcmp(app.SpecOption,'Cubesat with Dragsail')
                app.ComplexButton.Enable = 'off';
                app.BallisticCoefficientEditField.Enable = 'off';
                app.BallisticCoefficientUnitLabel.Enable = 'off';
                app.BallisticCoefficientEditFieldLabel.Enable = 'off';
%                 app.MassEditField.Enable = 'on';
%                 app.MassUnitLabel.Enable = 'on';
%                 app.MassEditFieldLabel.Enable = 'on';
%                 app.CdEditField.Enable = 'on';
%                 app.CdUnitLabel.Enable = 'on';
%                 app.CdEditFieldLabel.Enable = 'on';  
                app.StepnumberEditField.Enable= 'on';
                app.StepnumberEditFieldLabel.Enable = 'on';
                app.StepnumberUnitLabel.Enable= 'on';
                app.MaxProjDragsailAreaEditField.Enable= 'on';
                app.MaxProjDragsailAreaEditFieldLabel.Enable = 'on';
                app.MaxProjDragsailAreaEditFieldUnitLabel.Enable= 'on';
                app.MinProjDragsailAreaEditField.Enable= 'on';
                app.MinProjDragsailAreaEditFieldLabel.Enable = 'on';
                app.MinProjDragsailAreaEditFieldUnitLabel.Enable= 'on';
                app.ObjDepthEditField.Enable= 'on';
                app.ObjDepthEditFieldLabel.Enable = 'on';
                app.ObjDepthUnitLabel.Enable= 'on';
                app.ObjHeightEditField.Enable= 'on';
                app.ObjHeightEditFieldLabel.Enable = 'on';
                app.ObjHeightUnitLabel.Enable= 'on';
                app.ObjWidthEditField.Enable= 'on';
                app.ObjWidthEditFieldLabel.Enable = 'on';
                app.ObjWidthUnitLabel.Enable= 'on';
            end
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.Mass = app.MassEditField.Value;  
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: CdEditField
        function CdEditFieldValueChanged(app, event)
            app.Cd = app.CdEditField.Value;   
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: APEditField
        function APEditFieldValueChanged(app, event)
            app.AP = app.APEditField.Value; 
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: PhiEditField
        function PhiEditFieldValueChanged(app, event)
            app.Phi0 = app.PhiEditField.Value; 
        end

        % Value changed function: TrueAnomalyEditField
        function TrueAnomalyEditFieldValueChanged(app, event)
            app.f = app.TrueAnomalyEditField.Value;   
        end

        % Value changed function: ObjWidthEditField
        function ObjWidthEditFieldValueChanged(app, event)
            app.ObjWidth = app.ObjWidthEditField.Value; 
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: ObjHeightEditField
        function ObjHeightEditFieldValueChanged(app, event)
            app.ObjHeight = app.ObjHeightEditField.Value; 
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: ObjDepthEditField
        function ObjDepthEditFieldValueChanged(app, event)
            app.ObjDepth = app.ObjDepthEditField.Value; 
        end

        % Value changed function: MinProjDragsailAreaEditField
        function MinProjDragsailAreaEditFieldValueChanged(app, event)
            app.MinProjDragsailArea = app.MinProjDragsailAreaEditField.Value;  
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: MaxProjDragsailAreaEditField
        function MaxProjDragsailAreaEditFieldValueChanged(app, event)
            app.MaxProjDragsailArea = app.MaxProjDragsailAreaEditField.Value;   
            app.DataChangedLamp.Color = 'red';
        end

        % Value changed function: StepnumberEditField
        function StepnumberEditFieldValueChanged(app, event)
            app.Stepnumber = app.StepnumberEditField.Value;   
            app.DataChangedLamp.Color = 'red';
        end

        % Selection changed function: ClearPlotsFluxSimulationButtonGroup
        function ClearPlotsFluxSimulationButtonGroupSelectionChanged(app, event)
            app.ClearPlotsFluxSimulationButtonGroup.SelectedObject
            if app.noButtonECP.Value  
                app.holdsECP = 'on';
            elseif app.yesButtonECP.Value
                app.holdsECP = 'off'; 
            end
        end

        % Selection changed function: EvaluationModeButtonGroup
        function EvaluationModeButtonGroupSelectionChanged(app, event)
            app.EvaluationModeButtonGroup.SelectedObject
            if app.ComplexButton.Value  
                app.ModeECP = 1;
            elseif app.ReducedButton.Value
                app.ModeECP = 2; 
            end           
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            [file,path] = uigetfile('.\Output\*.xlsx;*.csv');
            filename = fullfile(path,file);
            [folder, baseFileName, fileExtension] = fileparts(filename);
            if ~isequal(file,0)
                ClearPlots(app)

                % Deletes additional Tables
                if app.SimN > 1
                    fn = fieldnames(app.Tab);
                    for k=1:numel(fn)
                        delete(app.Tab.(fn{k}));
                        delete(app.Table.(fn{k}));
                    end
                end
                app.trGroundPlot = {};
                app.trhfinalPlot = {};
                app.SimN = 0;
                app.Orbit3DPlot = {};
                app.trPlot = {};
                app.Orbit3DPlotList = {};
                app.LegendArray = {};
                if strcmp(fileExtension,'.xlsx')
                    [~, sheets] = xlsfinfo(filename);
                    SheetNumber = numel(sheets);                
                    for ii = 1:SheetNumber
                        app.SimN = ii;
                        data = readmatrix(filename, 'Sheet', sheets{ii});
                        app.SimOut.t = data(:, 1);
                        app.SimOut.r = data(:, 2);
                        app.SimOut.Phi = data(:, 3);
                        app.SimOut.altitude = data(:, 4);
                        app.SimOut.Vges = data(:, 5);
                        app.SimOut.a = data(:, 6);
                        app.SimOut.e = data(:, 7);
                        app.SimOut.f = data(:, 8);
                        app.SimOut.AP = data(:, 9);
                        app.SimOut.LAN = data(:, 10);
                        app.SimOut.i = data(:, 11);
                        app.SimOut.TL = data(:, 12);
                        app.SimOut.AL = data(:, 13);
                        app.SimOut.LP = data(:, 14);
                        app.SimOut.Theta = data(:, 15);
                        app.SimOut.x2D = data(:, 16);
                        app.SimOut.y2D = data(:, 17);
                        app.SimOut.x3D = data(:, 18);
                        app.SimOut.y3D = data(:, 19);
                        app.SimOut.z3D = data(:, 20);
                        app.SimOut.VgesSp = data(:, 21);
                        app.SimOut.epsilon = data(:, 22);
                        rhoV = [];
                        plotting(app,app.SimOut,rhoV);
                    end
                elseif strcmp(fileExtension,'.csv')                   
                    filenamess{1,1} = filename; % Initialisierung des Cell-Arrays
                    num = 1;
                    maxNum = 100; % Maximale Nummerierungsgrenze (Sie können dies anpassen)
                    % Schleife wird mindestens einmal ausgeführt
                    while true
                        % Erzeuge den Dateinamen mit der aktuellen Nummer
                        numberedFilename = sprintf('%s_%d%s', baseFileName, num, fileExtension);
                        
                        % Überprüfen, ob die Datei mit der Nummer existiert
                        if exist(fullfile(folder, numberedFilename), 'file') == 2
                            filenamess{num+1,1} = fullfile(folder, numberedFilename);
                        else
                            break; % Schleife beenden, wenn keine passende Datei gefunden wurde
                        end

                        num = num + 1;

                        % Überprüfen, ob die maximale Nummerierungsgrenze erreicht ist
                        if num > maxNum
                            break;
                        end

                    end
                    numHeaderLines = 1;
                    for ii = 1:size(filenamess,1)
                        app.SimN = ii;
                        data = readmatrix(filenamess{ii,1}, 'NumHeaderLines', numHeaderLines);
                        app.SimOut.t = data(:, 1);
                        app.SimOut.r = data(:, 2);
                        app.SimOut.Phi = data(:, 3);
                        app.SimOut.altitude = data(:, 4);
                        app.SimOut.Vges = data(:, 5);
                        app.SimOut.a = data(:, 6);
                        app.SimOut.e = data(:, 7);
                        app.SimOut.f = data(:, 8);
                        app.SimOut.AP = data(:, 9);
                        app.SimOut.LAN = data(:, 10);
                        app.SimOut.i = data(:, 11);
                        app.SimOut.TL = data(:, 12);
                        app.SimOut.AL = data(:, 13);
                        app.SimOut.LP = data(:, 14);
                        app.SimOut.Theta = data(:, 15);
                        app.SimOut.x2D = data(:, 16);
                        app.SimOut.y2D = data(:, 17);
                        app.SimOut.x3D = data(:, 18);
                        app.SimOut.y3D = data(:, 19);
                        app.SimOut.z3D = data(:, 20);
                        app.SimOut.VgesSp = data(:, 21);
                        app.SimOut.epsilon = data(:, 22);
                        rhoV = [];
                        plotting(app,app.SimOut,rhoV);
                    end
                end
                uialert(app.UIFigure,'Simulationdata successfully loaded!', 'Orbitsimulation','Icon','success'); 
                [folder, baseFileName] = fileparts(filename);
                newBaseFileName = append(baseFileName,'.ini');
                app.filenames = fullfile(folder, newBaseFileName);
                if exist(app.filenames,'file')
                    LoadSettingsMenuSelected(app)
                    for ii = 1 : app.SimN
                        if ii == 1
                        % Adding Simulation Date For ECP Function
                        app.SimStartDates = app.DateTime;
                        else
                        % Adding Simulation Date For ECP Function
                        app.SimStartDates = cat(1, app.SimStartDates, app.DateTime);
                        end
                    end
                    app.DataChangedLamp.Color = 'green'; 
                else
                    uialert(app.UIFigure,'Settingsfile not found!', 'Orbitsimulation','Icon','warning');
                    app.DataChangedLamp.Color = 'red';
                end
                app.filenames = '';               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataHandlingMenu = uimenu(app.UIFigure);
            app.DataHandlingMenu.Text = 'Data Handling';
            app.SaveDataMenu = uimenu(app.DataHandlingMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Text = 'Save Data';
            app.SaveDataasMenu = uimenu(app.DataHandlingMenu);
            app.SaveDataasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataasMenuSelected, true);
            app.SaveDataasMenu.Text = 'Save Data as';
            app.LoadDataMenu = uimenu(app.DataHandlingMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.SaveSettingsMenu = uimenu(app.DataHandlingMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.LoadSettingsMenu = uimenu(app.DataHandlingMenu);
            app.LoadSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsMenuSelected, true);
            app.LoadSettingsMenu.Text = 'Load Settings';
            app.LoadTLEMenu = uimenu(app.DataHandlingMenu);
            app.LoadTLEMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTLEMenuSelected, true);
            app.LoadTLEMenu.Text = 'Load TLE';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.AddPlotMenu = uimenu(app.PlotsMenu);
            app.AddPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPlotMenuSelected, true);
            app.AddPlotMenu.Text = 'Add Plot';
            app.DeletePlotMenu = uimenu(app.PlotsMenu);
            app.DeletePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletePlotMenuSelected, true);
            app.DeletePlotMenu.Text = 'Delete Plot';
            app.GridLayout7 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout7);
            app.FinalValuesTab = uitab(app.TabGroup);
            app.FinalValuesTab.Tooltip = {'Last values of Simulation.'};
            app.FinalValuesTab.Title = 'Final Values';
            app.FinalValsTable = uitable(app.FinalValuesTab);
            app.FinalValsTable.ColumnName = {''; 'Sim 1'};
            app.FinalValsTable.RowName = {'h [km]'; 't [d]'; 'revs'; 'rl [km]'; 'a [km]'; 'e []'};
            app.DataTable = uitab(app.TabGroup);
            app.DataTable.Tooltip = {'Table with several calculated values.'};
            app.DataTable.Title = 'Data Table';
            app.TabGroup2 = uitabgroup(app.DataTable);
            app.SimTableTab1 = uitab(app.TabGroup2);
            app.SimTableTab1.Title = 'Sim 1';
            app.SimTable1 = uitable(app.SimTableTab1);
            app.SimTable1.ColumnName = {'t [d]'; 'r [km]'; 'phi [°]'; 'h [km]'; 'v [km/s]'; 'a [km]'; 'e []'; 'f [°]'; 'AP [°]'; 'LAN[°]'; 'i [°]'};
            app.Orbit2DTab = uitab(app.TabGroup);
            app.Orbit2DTab.Title = 'Orbit 2D';
            app.UIAxes2 = uiaxes(app.Orbit2DTab);
            title(app.UIAxes2, 'Orbit 2D')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Orbit3DTab = uitab(app.TabGroup);
            app.Orbit3DTab.Title = 'Orbit 3D';
            app.UIAxes5 = uiaxes(app.Orbit3DTab);
            title(app.UIAxes5, 'Orbit 3D')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.ButtonGroup = uibuttongroup(app.Orbit3DTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BlackWhiteButton = uiradiobutton(app.ButtonGroup);
            app.BlackWhiteButton.Enable = 'off';
            app.BlackWhiteButton.Text = 'Black/White';
            app.ColoredButton = uiradiobutton(app.ButtonGroup);
            app.ColoredButton.Enable = 'off';
            app.ColoredButton.Text = 'Colored';
            app.ColoredButton.Value = true;
            app.ShowEarthCheckBox = uicheckbox(app.Orbit3DTab);
            app.ShowEarthCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowEarthCheckBoxValueChanged, true);
            app.ShowEarthCheckBox.Enable = 'off';
            app.ShowEarthCheckBox.Text = 'Show Earth';
            app.ShowEarthCheckBox.Value = true;
            app.rhohTab = uitab(app.TabGroup);
            app.rhohTab.Title = 'rho,h';
            app.UIAxes6 = uiaxes(app.rhohTab);
            title(app.UIAxes6, 'rho,h')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.notdefinedrhoLabel = uilabel(app.rhohTab);
            app.notdefinedrhoLabel.Text = 'not defined';
            app.trTab = uitab(app.TabGroup);
            app.trTab.Title = 't,r';
            app.UIAxes = uiaxes(app.trTab);
            title(app.UIAxes, 't,r')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'r')
            zlabel(app.UIAxes, 'Z')
            app.SimulatedValuesCheckBox = uicheckbox(app.trTab);
            app.SimulatedValuesCheckBox.Enable = 'off';
            app.SimulatedValuesCheckBox.Visible = 'off';
            app.SimulatedValuesCheckBox.Text = 'Simulated Values';
            app.AverageValuesCheckBox = uicheckbox(app.trTab);
            app.AverageValuesCheckBox.Enable = 'off';
            app.AverageValuesCheckBox.Visible = 'off';
            app.AverageValuesCheckBox.Text = 'Average Values';
            app.RunOrbitSimulationButton = uibutton(app.GridLayout7, 'push');
            app.RunOrbitSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOrbitSimulationButtonPushed, true);
            app.RunOrbitSimulationButton.Tooltip = {'This function simulates the Orbitpropagation of the chosen Object'};
            app.RunOrbitSimulationButton.Text = 'Run Orbit Simulation';
            app.CancelButton = uibutton(app.GridLayout7, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OrbitParametersPanel = uipanel(app.GridLayout7);
            app.OrbitParametersPanel.Title = 'Orbit Parameters';
            app.GridLayout2 = uigridlayout(app.OrbitParametersPanel);
            app.HeightUnitLabel = uilabel(app.GridLayout2);
            app.HeightUnitLabel.Text = '[km]';
            app.PhiUnitLabel = uilabel(app.GridLayout2);
            app.PhiUnitLabel.Text = '[°]';
            app.InclinationUnitLabel = uilabel(app.GridLayout2);
            app.InclinationUnitLabel.Visible = 'off';
            app.InclinationUnitLabel.Text = '[°]';
            app.SemimajoraxisUnitLabel = uilabel(app.GridLayout2);
            app.SemimajoraxisUnitLabel.Visible = 'off';
            app.SemimajoraxisUnitLabel.Text = '[km]';
            app.EccentricityUnitLabel = uilabel(app.GridLayout2);
            app.EccentricityUnitLabel.Visible = 'off';
            app.EccentricityUnitLabel.Text = '[]';
            app.LANUnitLabel = uilabel(app.GridLayout2);
            app.LANUnitLabel.Visible = 'off';
            app.LANUnitLabel.Text = '[°]';
            app.APUnitLabel = uilabel(app.GridLayout2);
            app.APUnitLabel.Visible = 'off';
            app.APUnitLabel.Text = '[°]';
            app.TrueAnomalyUnitLabel = uilabel(app.GridLayout2);
            app.TrueAnomalyUnitLabel.Visible = 'off';
            app.TrueAnomalyUnitLabel.Text = '[°]';
            app.ThetaUnitLabel = uilabel(app.GridLayout2);
            app.ThetaUnitLabel.Text = '[°]';
            app.rDotEditField = uieditfield(app.GridLayout2, 'numeric');
            app.rDotEditField.ValueChangedFcn = createCallbackFcn(app, @rDotEditFieldValueChanged, true);
            app.rDotUnitLabel = uilabel(app.GridLayout2);
            app.rDotUnitLabel.Text = '[m/s]';
            app.PhiDotUnitLabel = uilabel(app.GridLayout2);
            app.PhiDotUnitLabel.Text = '[murad/s]';
            app.ThetaDotUnitLabel = uilabel(app.GridLayout2);
            app.ThetaDotUnitLabel.Text = '[murad/s]';
            app.rDotEditFieldLabel = uilabel(app.GridLayout2);
            app.rDotEditFieldLabel.Text = 'rDot';
            app.TLUnitLabel = uilabel(app.GridLayout2);
            app.TLUnitLabel.Visible = 'off';
            app.TLUnitLabel.Text = '[°]';
            app.ALUnitLabel = uilabel(app.GridLayout2);
            app.ALUnitLabel.Visible = 'off';
            app.ALUnitLabel.Text = '[°]';
            app.LPUnitLabel = uilabel(app.GridLayout2);
            app.LPUnitLabel.Visible = 'off';
            app.LPUnitLabel.Text = '[°]';
            app.HeightEditFieldLabel = uilabel(app.GridLayout2);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.GridLayout2, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 400;
            app.InclinationEditFieldLabel = uilabel(app.GridLayout2);
            app.InclinationEditFieldLabel.Visible = 'off';
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InclinationEditField.Limits = [0 180];
            app.InclinationEditField.ValueChangedFcn = createCallbackFcn(app, @InclinationEditFieldValueChanged, true);
            app.InclinationEditField.Visible = 'off';
            app.InclinationEditField.Value = 28.5;
            app.SemimajorAxisEditFieldLabel = uilabel(app.GridLayout2);
            app.SemimajorAxisEditFieldLabel.Visible = 'off';
            app.SemimajorAxisEditFieldLabel.Text = 'Semi-major Axis';
            app.SemimajorAxisEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SemimajorAxisEditField.Limits = [0 Inf];
            app.SemimajorAxisEditField.ValueChangedFcn = createCallbackFcn(app, @SemimajorAxisEditFieldValueChanged, true);
            app.SemimajorAxisEditField.Visible = 'off';
            app.SemimajorAxisEditField.Value = 7000;
            app.EccentricityEditFieldLabel = uilabel(app.GridLayout2);
            app.EccentricityEditFieldLabel.Visible = 'off';
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EccentricityEditField.Limits = [0 Inf];
            app.EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @EccentricityEditFieldValueChanged, true);
            app.EccentricityEditField.Visible = 'off';
            app.EccentricityEditField.Value = 0.0001;
            app.LANLabel = uilabel(app.GridLayout2);
            app.LANLabel.Visible = 'off';
            app.LANLabel.Tooltip = {'Longitude of ascending node'};
            app.LANLabel.Text = 'RAAN';
            app.LANEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LANEditField.Limits = [0 Inf];
            app.LANEditField.ValueChangedFcn = createCallbackFcn(app, @LANEditFieldValueChanged2, true);
            app.LANEditField.Visible = 'off';
            app.LANEditField.Tooltip = {'Longitude of ascending node'};
            app.ReferenceSystemDropDownLabel = uilabel(app.GridLayout2);
            app.ReferenceSystemDropDownLabel.Text = 'Reference System';
            app.ReferenceSystemDropDown = uidropdown(app.GridLayout2);
            app.ReferenceSystemDropDown.Items = {'Spherical Coordinates', 'Orbital Elements'};
            app.ReferenceSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceSystemDropDownValueChanged, true);
            app.ReferenceSystemDropDown.Value = 'Spherical Coordinates';
            app.ThetaEditFieldLabel = uilabel(app.GridLayout2);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ThetaEditField.Limits = [0 360];
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.PhiEditFieldLabel = uilabel(app.GridLayout2);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PhiEditField.Limits = [0 360];
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
            app.ThetaDotEditFieldLabel = uilabel(app.GridLayout2);
            app.ThetaDotEditFieldLabel.Text = 'ThetaDot';
            app.ThetaDotEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ThetaDotEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaDotEditFieldValueChanged, true);
            app.APLabel = uilabel(app.GridLayout2);
            app.APLabel.Visible = 'off';
            app.APLabel.Tooltip = {'Argument of pericenter'};
            app.APLabel.Text = 'AoP';
            app.APEditField = uieditfield(app.GridLayout2, 'numeric');
            app.APEditField.Limits = [0 360];
            app.APEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField.Enable = 'off';
            app.APEditField.Visible = 'off';
            app.APEditField.Tooltip = {'Argument of pericenter. This settings is currently deactivated.'};
            app.TrueAnomalyEditFieldLabel = uilabel(app.GridLayout2);
            app.TrueAnomalyEditFieldLabel.Visible = 'off';
            app.TrueAnomalyEditFieldLabel.Text = 'True Anomaly';
            app.TrueAnomalyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TrueAnomalyEditField.Limits = [0 360];
            app.TrueAnomalyEditField.ValueChangedFcn = createCallbackFcn(app, @TrueAnomalyEditFieldValueChanged, true);
            app.TrueAnomalyEditField.Enable = 'off';
            app.TrueAnomalyEditField.Visible = 'off';
            app.TrueAnomalyEditField.Tooltip = {'This settings is currently deactivated.'};
            app.PhiDotEditFieldLabel = uilabel(app.GridLayout2);
            app.PhiDotEditFieldLabel.Text = 'PhiDot';
            app.PhiDotEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PhiDotEditField.ValueChangedFcn = createCallbackFcn(app, @PhiDotEditFieldValueChanged, true);
            app.PhiDotEditField.Value = 1131.3666536095;
            app.TLEditFieldLabel = uilabel(app.GridLayout2);
            app.TLEditFieldLabel.Visible = 'off';
            app.TLEditFieldLabel.Tooltip = {'True longitude (for e=0 and i=0)'};
            app.TLEditFieldLabel.Text = 'TL';
            app.TLEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TLEditField.Limits = [0 Inf];
            app.TLEditField.ValueChangedFcn = createCallbackFcn(app, @TLEditFieldValueChanged, true);
            app.TLEditField.Enable = 'off';
            app.TLEditField.Visible = 'off';
            app.TLEditField.Tooltip = {'True longitude (for e=0 and i=0). This settings is currently deactivated.'};
            app.ALLabel = uilabel(app.GridLayout2);
            app.ALLabel.Visible = 'off';
            app.ALLabel.Tooltip = {'Argument of latitude (for e= 0)'};
            app.ALLabel.Text = 'AL';
            app.ALEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ALEditField.Limits = [0 Inf];
            app.ALEditField.ValueChangedFcn = createCallbackFcn(app, @ALEditFieldValueChanged, true);
            app.ALEditField.Enable = 'off';
            app.ALEditField.Visible = 'off';
            app.ALEditField.Tooltip = {'Argument of latitude (for e= 0). This settings is currently deactivated.'};
            app.LPEditFieldLabel = uilabel(app.GridLayout2);
            app.LPEditFieldLabel.Visible = 'off';
            app.LPEditFieldLabel.Tooltip = {'Longitude of pericenter (for i=0)'};
            app.LPEditFieldLabel.Text = 'LP';
            app.LPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LPEditField.Limits = [0 Inf];
            app.LPEditField.ValueChangedFcn = createCallbackFcn(app, @LPEditFieldValueChanged, true);
            app.LPEditField.Enable = 'off';
            app.LPEditField.Visible = 'off';
            app.LPEditField.Tooltip = {'Longitude of pericenter (for i=0). This settings is currently deactivated.'};
            app.AtmosphereModelPanel = uipanel(app.GridLayout7);
            app.AtmosphereModelPanel.Title = 'Atmosphere Model';
            app.GridLayout4 = uigridlayout(app.AtmosphereModelPanel);
            app.AtmosphereModelsDropDownLabel = uilabel(app.GridLayout4);
            app.AtmosphereModelsDropDownLabel.Text = 'Atmosphere Models';
            app.AtmosphereModelsDropDown = uidropdown(app.GridLayout4);
            app.AtmosphereModelsDropDown.Items = {'None', 'Exponential', 'nrlmsise00'};
            app.AtmosphereModelsDropDown.ValueChangedFcn = createCallbackFcn(app, @AtmosphereModelsDropDownValueChanged, true);
            app.AtmosphereModelsDropDown.Value = 'None';
            app.SolarFluxDropDownLabel = uilabel(app.GridLayout4);
            app.SolarFluxDropDownLabel.Enable = 'off';
            app.SolarFluxDropDownLabel.Text = 'Solar Flux';
            app.SolarFluxDropDown = uidropdown(app.GridLayout4);
            app.SolarFluxDropDown.Items = {'Low', 'Medium', 'High'};
            app.SolarFluxDropDown.ValueChangedFcn = createCallbackFcn(app, @SolarFluxDropDownValueChanged, true);
            app.SolarFluxDropDown.Enable = 'off';
            app.SolarFluxDropDown.Value = 'Low';
            app.aphDropDownLabel = uilabel(app.GridLayout4);
            app.aphDropDownLabel.Enable = 'off';
            app.aphDropDownLabel.Text = 'aph';
            app.aphDropDown = uidropdown(app.GridLayout4);
            app.aphDropDown.Items = {'Low', 'Medium', 'High'};
            app.aphDropDown.ValueChangedFcn = createCallbackFcn(app, @aphDropDownValueChanged, true);
            app.aphDropDown.Enable = 'off';
            app.aphDropDown.Value = 'Low';
            app.ObjectParametersPanel = uipanel(app.GridLayout7);
            app.ObjectParametersPanel.Title = 'Object Parameters';
            app.GridLayout = uigridlayout(app.ObjectParametersPanel);
            app.BallisticCoefficientUnitLabel = uilabel(app.GridLayout);
            app.BallisticCoefficientUnitLabel.Tooltip = {'Several Values could be typed in. Please insert a space in between.'};
            app.BallisticCoefficientUnitLabel.Text = '[kg/m^2]';
            app.LitftoDragratioUnitLabel = uilabel(app.GridLayout);
            app.LitftoDragratioUnitLabel.Enable = 'off';
            app.LitftoDragratioUnitLabel.Text = '[]';
            app.LifttoDragratioEditFieldLabel = uilabel(app.GridLayout);
            app.LifttoDragratioEditFieldLabel.Enable = 'off';
            app.LifttoDragratioEditFieldLabel.Text = 'Lift to Drag ratio';
            app.LifttoDragratioEditField = uieditfield(app.GridLayout, 'numeric');
            app.LifttoDragratioEditField.Limits = [0 Inf];
            app.LifttoDragratioEditField.ValueChangedFcn = createCallbackFcn(app, @LifttoDragratioEditFieldValueChanged, true);
            app.LifttoDragratioEditField.Enable = 'off';
            app.BallisticCoefficientEditFieldLabel = uilabel(app.GridLayout);
            app.BallisticCoefficientEditFieldLabel.Tooltip = {'Several Values could be typed in. Please insert a space in between.'};
            app.BallisticCoefficientEditFieldLabel.Text = 'Ballistic Coefficient';
            app.BallisticCoefficientEditField = uieditfield(app.GridLayout, 'text');
            app.BallisticCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @BallisticCoefficientEditFieldValueChanged, true);
            app.BallisticCoefficientEditField.Tooltip = {''};
            app.BallisticCoefficientEditField.Value = '1';
            app.MassEditFieldLabel = uilabel(app.GridLayout);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.GridLayout, 'numeric');
            app.MassEditField.LowerLimitInclusive = 'off';
            app.MassEditField.Limits = [0 Inf];
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.MassEditField.Value = 54;
            app.CdEditFieldLabel = uilabel(app.GridLayout);
            app.CdEditFieldLabel.Text = 'Cd';
            app.CdEditField = uieditfield(app.GridLayout, 'numeric');
            app.CdEditField.LowerLimitInclusive = 'off';
            app.CdEditField.Limits = [0 Inf];
            app.CdEditField.ValueChangedFcn = createCallbackFcn(app, @CdEditFieldValueChanged, true);
            app.CdEditField.Tooltip = {'The Cd Value stays constant for the complete Simulation.'};
            app.CdEditField.Value = 2.2;
            app.SpecOptionDropDownLabel = uilabel(app.GridLayout);
            app.SpecOptionDropDownLabel.Text = 'Spec. Option';
            app.SpecOptionDropDown = uidropdown(app.GridLayout);
            app.SpecOptionDropDown.Items = {'Sphere', 'Cubesat', 'Cubesat with Dragsail'};
            app.SpecOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecOptionDropDownValueChanged, true);
            app.SpecOptionDropDown.Value = 'Sphere';
            app.MassUnitLabel = uilabel(app.GridLayout);
            app.MassUnitLabel.Text = '[kg]';
            app.CdUnitLabel = uilabel(app.GridLayout);
            app.CdUnitLabel.Text = '[]';
            app.ObjWidthEditFieldLabel = uilabel(app.GridLayout);
            app.ObjWidthEditFieldLabel.Enable = 'off';
            app.ObjWidthEditFieldLabel.Text = 'Width';
            app.ObjWidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.ObjWidthEditField.LowerLimitInclusive = 'off';
            app.ObjWidthEditField.Limits = [0 Inf];
            app.ObjWidthEditField.ValueChangedFcn = createCallbackFcn(app, @ObjWidthEditFieldValueChanged, true);
            app.ObjWidthEditField.Enable = 'off';
            app.ObjWidthEditField.Value = 50;
            app.ObjHeightEditFieldLabel = uilabel(app.GridLayout);
            app.ObjHeightEditFieldLabel.Enable = 'off';
            app.ObjHeightEditFieldLabel.Text = 'Height';
            app.ObjHeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.ObjHeightEditField.LowerLimitInclusive = 'off';
            app.ObjHeightEditField.Limits = [0 Inf];
            app.ObjHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ObjHeightEditFieldValueChanged, true);
            app.ObjHeightEditField.Enable = 'off';
            app.ObjHeightEditField.Value = 50;
            app.ObjDepthEditFieldLabel = uilabel(app.GridLayout);
            app.ObjDepthEditFieldLabel.Enable = 'off';
            app.ObjDepthEditFieldLabel.Text = 'Depth';
            app.ObjDepthEditField = uieditfield(app.GridLayout, 'numeric');
            app.ObjDepthEditField.LowerLimitInclusive = 'off';
            app.ObjDepthEditField.Limits = [0 Inf];
            app.ObjDepthEditField.ValueChangedFcn = createCallbackFcn(app, @ObjDepthEditFieldValueChanged, true);
            app.ObjDepthEditField.Enable = 'off';
            app.ObjDepthEditField.Value = 50;
            app.MaxProjDragsailAreaEditFieldLabel = uilabel(app.GridLayout);
            app.MaxProjDragsailAreaEditFieldLabel.Enable = 'off';
            app.MaxProjDragsailAreaEditFieldLabel.Text = 'Max Proj. Dragsail Area';
            app.MaxProjDragsailAreaEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxProjDragsailAreaEditField.Limits = [0 Inf];
            app.MaxProjDragsailAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MaxProjDragsailAreaEditFieldValueChanged, true);
            app.MaxProjDragsailAreaEditField.Enable = 'off';
            app.MaxProjDragsailAreaEditField.Value = 10;
            app.StepnumberEditFieldLabel = uilabel(app.GridLayout);
            app.StepnumberEditFieldLabel.Enable = 'off';
            app.StepnumberEditFieldLabel.Text = 'Stepnumber';
            app.StepnumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.StepnumberEditField.LowerLimitInclusive = 'off';
            app.StepnumberEditField.Limits = [0 Inf];
            app.StepnumberEditField.ValueChangedFcn = createCallbackFcn(app, @StepnumberEditFieldValueChanged, true);
            app.StepnumberEditField.Enable = 'off';
            app.StepnumberEditField.Tooltip = {'Number of Dragsailsizes'};
            app.StepnumberEditField.Value = 3;
            app.ObjWidthUnitLabel = uilabel(app.GridLayout);
            app.ObjWidthUnitLabel.Enable = 'off';
            app.ObjWidthUnitLabel.Text = '[cm]';
            app.ObjHeightUnitLabel = uilabel(app.GridLayout);
            app.ObjHeightUnitLabel.Enable = 'off';
            app.ObjHeightUnitLabel.Text = '[cm]';
            app.ObjDepthUnitLabel = uilabel(app.GridLayout);
            app.ObjDepthUnitLabel.Enable = 'off';
            app.ObjDepthUnitLabel.Text = '[cm]';
            app.MaxProjDragsailAreaEditFieldUnitLabel = uilabel(app.GridLayout);
            app.MaxProjDragsailAreaEditFieldUnitLabel.Enable = 'off';
            app.MaxProjDragsailAreaEditFieldUnitLabel.Text = '[m^2]';
            app.StepnumberUnitLabel = uilabel(app.GridLayout);
            app.StepnumberUnitLabel.Enable = 'off';
            app.StepnumberUnitLabel.Text = '[]';
            app.CubesatSizeLabel = uilabel(app.GridLayout);
            app.CubesatSizeLabel.Text = 'Cubesat Size';
            app.DragsailSizeLabel = uilabel(app.GridLayout);
            app.DragsailSizeLabel.Text = 'Dragsail Size';
            app.MinProjDragsailAreaEditFieldLabel = uilabel(app.GridLayout);
            app.MinProjDragsailAreaEditFieldLabel.Enable = 'off';
            app.MinProjDragsailAreaEditFieldLabel.Text = 'Min. Proj. Dragsail Area';
            app.MinProjDragsailAreaEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinProjDragsailAreaEditField.LowerLimitInclusive = 'off';
            app.MinProjDragsailAreaEditField.Limits = [0 Inf];
            app.MinProjDragsailAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinProjDragsailAreaEditFieldValueChanged, true);
            app.MinProjDragsailAreaEditField.Enable = 'off';
            app.MinProjDragsailAreaEditField.Value = 2;
            app.MinProjDragsailAreaEditFieldUnitLabel = uilabel(app.GridLayout);
            app.MinProjDragsailAreaEditFieldUnitLabel.Enable = 'off';
            app.MinProjDragsailAreaEditFieldUnitLabel.Text = '[m^2]';
            app.EvaluationModeButtonGroup = uibuttongroup(app.GridLayout);
            app.EvaluationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EvaluationModeButtonGroupSelectionChanged, true);
            app.EvaluationModeButtonGroup.Title = 'Evaluation Mode';
            app.ComplexButton = uiradiobutton(app.EvaluationModeButtonGroup);
            app.ComplexButton.Tooltip = {'The complex mode uses MASTER for generating data. It evaluates only data for initial cell.'};
            app.ComplexButton.Text = 'Complex';
            app.ReducedButton = uiradiobutton(app.EvaluationModeButtonGroup);
            app.ReducedButton.Tooltip = {'The Reduced Mode uses a timeefficient Database.'};
            app.ReducedButton.Text = 'Reduced';
            app.ReducedButton.Value = true;
            app.OrbitSimulationSpecificationsPanel = uipanel(app.GridLayout7);
            app.OrbitSimulationSpecificationsPanel.Title = 'Orbit Simulation Specifications';
            app.GridLayout3 = uigridlayout(app.OrbitSimulationSpecificationsPanel);
            app.SimulationTimeUnitLabel = uilabel(app.GridLayout3);
            app.SimulationTimeUnitLabel.Text = '[d]';
            app.FinalHeightUnitLabel = uilabel(app.GridLayout3);
            app.FinalHeightUnitLabel.Text = '[km]';
            app.StepSizeUnitLabel = uilabel(app.GridLayout3);
            app.StepSizeUnitLabel.Text = '[]';
            app.RelErrUnitLabel = uilabel(app.GridLayout3);
            app.RelErrUnitLabel.Text = '[]';
            app.AbsErrUnitLabel = uilabel(app.GridLayout3);
            app.AbsErrUnitLabel.Text = '[]';
            app.SimulationTimeEditFieldLabel = uilabel(app.GridLayout3);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SimulationTimeEditField.LowerLimitInclusive = 'off';
            app.SimulationTimeEditField.Limits = [0 Inf];
            app.SimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeEditFieldValueChanged, true);
            app.SimulationTimeEditField.Value = 1;
            app.FinalHeightEditFieldLabel = uilabel(app.GridLayout3);
            app.FinalHeightEditFieldLabel.Text = 'Final Height';
            app.FinalHeightEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FinalHeightEditField.Limits = [0 Inf];
            app.FinalHeightEditField.ValueChangedFcn = createCallbackFcn(app, @FinalHeightEditFieldValueChanged, true);
            app.FinalHeightEditField.Value = 120;
            app.StepSizeEditFieldLabel = uilabel(app.GridLayout3);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.StepSizeEditField.Limits = [0 Inf];
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Tooltip = {'Suggested is a Stepsize = days * 100'};
            app.StepSizeEditField.Value = 100;
            app.RelErrorTolEditFieldLabel = uilabel(app.GridLayout3);
            app.RelErrorTolEditFieldLabel.Text = 'Rel. Error Tol.';
            app.RelErrorTolEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RelErrorTolEditField.LowerLimitInclusive = 'off';
            app.RelErrorTolEditField.Limits = [0 Inf];
            app.RelErrorTolEditField.ValueChangedFcn = createCallbackFcn(app, @RelErrorTolEditFieldValueChanged, true);
            app.RelErrorTolEditField.Value = 1e-07;
            app.AbsErrorTolEditFieldLabel = uilabel(app.GridLayout3);
            app.AbsErrorTolEditFieldLabel.Text = 'Abs. Error Tol.';
            app.AbsErrorTolEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AbsErrorTolEditField.LowerLimitInclusive = 'off';
            app.AbsErrorTolEditField.Limits = [0 Inf];
            app.AbsErrorTolEditField.ValueChangedFcn = createCallbackFcn(app, @AbsErrorTolEditFieldValueChanged, true);
            app.AbsErrorTolEditField.Value = 1e-08;
            app.ODEDropDownLabel = uilabel(app.GridLayout3);
            app.ODEDropDownLabel.Text = 'ODE';
            app.ODEDropDown = uidropdown(app.GridLayout3);
            app.ODEDropDown.Items = {'23', '87', '45', '113', '15s', '23s', '23t', '23tb', '78', '89'};
            app.ODEDropDown.ValueChangedFcn = createCallbackFcn(app, @ODEDropDownValueChanged, true);
            app.ODEDropDown.Value = '45';
            app.ClearPlotsOrbitSimulationButtonGroup = uibuttongroup(app.GridLayout7);
            app.ClearPlotsOrbitSimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClearPlotsOrbitSimulationButtonGroupSelectionChanged, true);
            app.ClearPlotsOrbitSimulationButtonGroup.Title = 'Clear Plots (Orbit Simulation)';
            app.yesButton = uiradiobutton(app.ClearPlotsOrbitSimulationButtonGroup);
            app.yesButton.Tooltip = {'''on'' adds Simulationdata to existing plots'; '''off'' clears plots bevor plotting.'};
            app.yesButton.Text = 'yes';
            app.yesButton.Value = true;
            app.noButton = uiradiobutton(app.ClearPlotsOrbitSimulationButtonGroup);
            app.noButton.Text = 'no';
            app.GravityModelPanel = uipanel(app.GridLayout7);
            app.GravityModelPanel.Title = 'Gravity Model';
            app.GridLayout6 = uigridlayout(app.GravityModelPanel);
            app.GravityModelDropDownLabel = uilabel(app.GridLayout6);
            app.GravityModelDropDownLabel.Text = 'Gravity Model';
            app.GravityModelDropDown = uidropdown(app.GridLayout6);
            app.GravityModelDropDown.Items = {'Point mass', 'J2 - Ellipsoid'};
            app.GravityModelDropDown.ValueChangedFcn = createCallbackFcn(app, @GravityModelDropDownValueChanged, true);
            app.GravityModelDropDown.Value = 'Point mass';
            app.TimeandDatePanel = uipanel(app.GridLayout7);
            app.TimeandDatePanel.Title = 'Time and Date';
            app.GridLayout5 = uigridlayout(app.TimeandDatePanel);
            app.HoursEditField = uieditfield(app.GridLayout5, 'numeric');
            app.HoursEditField.Limits = [0 Inf];
            app.HoursEditField.ValueChangedFcn = createCallbackFcn(app, @HoursEditFieldValueChanged, true);
            app.HoursEditField.Tooltip = {'Hours'};
            app.SecondsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SecondsEditField.Limits = [0 Inf];
            app.SecondsEditField.ValueChangedFcn = createCallbackFcn(app, @SecondsEditFieldValueChanged, true);
            app.SecondsEditField.Tooltip = {'Seconds'};
            app.Ddot1Label = uilabel(app.GridLayout5);
            app.Ddot1Label.Text = ':';
            app.Ddot2Label = uilabel(app.GridLayout5);
            app.Ddot2Label.Text = ':';
            app.TimeLabel = uilabel(app.GridLayout5);
            app.TimeLabel.Text = 'Time';
            app.MinutesEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MinutesEditField.Limits = [0 Inf];
            app.MinutesEditField.ValueChangedFcn = createCallbackFcn(app, @MinutesEditFieldValueChanged, true);
            app.MinutesEditField.Tooltip = {'Minutes'};
            app.UTCLabel = uilabel(app.GridLayout5);
            app.UTCLabel.Text = '[UTC]';
            app.InitialDateDatePickerLabel = uilabel(app.GridLayout5);
            app.InitialDateDatePickerLabel.Text = 'Initial Date';
            app.InitialDateDatePicker = uidatepicker(app.GridLayout5);
            app.InitialDateDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.InitialDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @InitialDateDatePickerValueChanged, true);
            app.InitialDateDatePicker.Value = datetime([2023 1 1]);
            app.RunFluxSimulationButton = uibutton(app.GridLayout7, 'push');
            app.RunFluxSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunFluxSimulationButtonPushed, true);
            app.RunFluxSimulationButton.Tooltip = {'This function generates the Flux impacting on the chosen Surfaces.'};
            app.RunFluxSimulationButton.Text = 'Run Flux Simulation';
            app.TabGroup3 = uitabgroup(app.GridLayout7);
            app.ObjMassvsFluxTab = uitab(app.TabGroup3);
            app.ObjMassvsFluxTab.Title = 'Obj. Mass vs Flux';
            app.UIAxes7 = uiaxes(app.ObjMassvsFluxTab);
            title(app.UIAxes7, 'Impactor Mass vs Average Impact Flux')
            xlabel(app.UIAxes7, 'Impactor Mass [kg]')
            ylabel(app.UIAxes7, 'Impact Flux [1/(m^2*yr)]')
            zlabel(app.UIAxes7, 'Z')
            app.ObjMassvstotalImpactsTab = uitab(app.TabGroup3);
            app.ObjMassvstotalImpactsTab.Title = 'Obj. Mass vs total Impacts';
            app.UIAxes8 = uiaxes(app.ObjMassvstotalImpactsTab);
            title(app.UIAxes8, 'Impactor Mass vs Total Impacts')
            xlabel(app.UIAxes8, 'Impactor Mass [kg]')
            ylabel(app.UIAxes8, 'Total Impacts []')
            zlabel(app.UIAxes8, 'Z')
            app.AverageImpactsoverMissionTimeTab = uitab(app.TabGroup3);
            app.AverageImpactsoverMissionTimeTab.Tooltip = {'Average Impacts vs Mission Time'};
            app.AverageImpactsoverMissionTimeTab.Title = 'Average Impacts over Mission Time';
            app.UIAxes9 = uiaxes(app.AverageImpactsoverMissionTimeTab);
            title(app.UIAxes9, 'Average Impacts over Mission Time')
            xlabel(app.UIAxes9, 'Mission Time [Days]')
            ylabel(app.UIAxes9, '2D Flux [1/yr]')
            zlabel(app.UIAxes9, 'Z')
            app.ClearPlotsFluxSimulationButtonGroup = uibuttongroup(app.GridLayout7);
            app.ClearPlotsFluxSimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClearPlotsFluxSimulationButtonGroupSelectionChanged, true);
            app.ClearPlotsFluxSimulationButtonGroup.Title = 'Clear Plots (Flux Simulation)';
            app.noButtonECP = uiradiobutton(app.ClearPlotsFluxSimulationButtonGroup);
            app.noButtonECP.Text = 'no';
            app.yesButtonECP = uiradiobutton(app.ClearPlotsFluxSimulationButtonGroup);
            app.yesButtonECP.Text = 'yes';
            app.yesButtonECP.Value = true;
            app.DataChangedLampLabel = uilabel(app.GridLayout7);
            app.DataChangedLampLabel.Text = 'Data Changed';
            app.DataChangedLamp = uilamp(app.GridLayout7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUISpaceDebrisSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end